classdef ProofAccel_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main                          matlab.ui.Figure
        FilesForCalculate             matlab.ui.container.Panel
        Label_2                       matlab.ui.control.Label
        EditDisplacementName          matlab.ui.control.EditField
        ButtonDefineDisplacementName  matlab.ui.control.Button
        Label_3                       matlab.ui.control.Label
        EditStartReadNumb             matlab.ui.control.NumericEditField
        ButtonDefineAccelName         matlab.ui.control.Button
        Label_9                       matlab.ui.control.Label
        EditAccelName                 matlab.ui.control.EditField
        CalculateSettings             matlab.ui.container.Panel
        Label_5                       matlab.ui.control.Label
        EditCutProcent                matlab.ui.control.NumericEditField
        Label_6                       matlab.ui.control.Label
        EditCutOffFrequency           matlab.ui.control.NumericEditField
        CheckBoxCorrectDisplacement   matlab.ui.control.CheckBox
        ButtonCalculate               matlab.ui.control.Button
        ButtonSave                    matlab.ui.control.Button
        Label_10                      matlab.ui.control.Label
        DropDownCorrectLength         matlab.ui.control.DropDown
        Label_11                      matlab.ui.control.Label
        EditLevelsStep                matlab.ui.control.NumericEditField
        Label_12                      matlab.ui.control.Label
        EditAccuracy                  matlab.ui.control.NumericEditField
        Label_14                      matlab.ui.control.Label
        EditAccuracyExp               matlab.ui.control.NumericEditField
        Label_15                      matlab.ui.control.Label
        EditLimExpAccelLevel          matlab.ui.control.NumericEditField
        Label_16                      matlab.ui.control.Label
        EditDepthGluing               matlab.ui.control.NumericEditField
        Label_17                      matlab.ui.control.Label
        EditFreqDecrement             matlab.ui.control.NumericEditField
        LabelMaxMin                   matlab.ui.control.Label
        ButtonLoadSettings            matlab.ui.control.Button
        Label_18                      matlab.ui.control.Label
        DropDownModelApprox           matlab.ui.control.DropDown
        LabelMaxFreq                  matlab.ui.control.Label
        Label_22                      matlab.ui.control.Label
        EditOverlapFactor             matlab.ui.control.NumericEditField
        Label_21                      matlab.ui.control.Label
        EditAccuracySpectrum          matlab.ui.control.NumericEditField
        CheckBoxNormalizeMode         matlab.ui.control.CheckBox
        ButtonAngleCoeffcients        matlab.ui.control.Button
        ShowResults                   matlab.ui.container.Panel
        ListBoxShowFigure             matlab.ui.control.ListBox
        Label_8                       matlab.ui.control.Label
        SpinnerShowNumb               matlab.ui.control.Spinner
        Label_13                      matlab.ui.control.Label
        SpinnerFixSpectrumLow         matlab.ui.control.Spinner
        CheckBoxLogScale              matlab.ui.control.CheckBox
        Label_19                      matlab.ui.control.Label
        SpinnerControlLevelNumb       matlab.ui.control.Spinner
        Label_20                      matlab.ui.control.Label
        SpinnerFixSpectrumHigh        matlab.ui.control.Spinner
        CheckBoxCompareInputSignal    matlab.ui.control.CheckBox
        CheckBoxSmoothingOption       matlab.ui.control.CheckBox
        InputFiles                    matlab.ui.container.Panel
        ListBoxInput                  matlab.ui.control.ListBox
        ButtonTrash                   matlab.ui.control.Button
        ButtonClear                   matlab.ui.control.Button
        ButtonAdd                     matlab.ui.control.Button
        ButtonInfo                    matlab.ui.control.Button
        ButtonGroupMultiPlotSettings  matlab.ui.container.ButtonGroup
        ButtonNothing                 matlab.ui.control.RadioButton
        ButtonZero                    matlab.ui.control.RadioButton
        ButtonLinear                  matlab.ui.control.RadioButton
        ButtonClearChoise             matlab.ui.control.Button
    end

    
    properties (Access = public) %Описание публичных переменных
        %Входные переменные для расчёт
        ScreenSize = zeros(1,4); %Размер экрана
        FileNameAccel = ''; %Имя файла с ускорениями
        FileNameDisplacement = ''; %Имя файла c перемещениями
        ShowNumb; %Номер линии уровня для отображения
        StartReadNumb; %Номер отсчетной строки
        LevelsStep; %Шаг по уровням
        Accuracy; %Точность аппроксимации
        CutProcent; %Процент усечения фрагментов
        CorrectLengthMode; %Режим приведения склеек по длине
        CorrectDisplacement; %Корректировка линейного дрейфа оборудования
        CutOffFrequency; %Частота среза, Гц
        AccuracyExp; %Точность выделения экспонециального затухания
        LimExpAccelLevel; %Предельное значение диапазона ускорений
        FreqDecrement; %Частота выделения декремента затухания
        DepthGluing; %Глубина прохода для склейки
        ModelApprox; %Модель выделения декремента затуханий
        OverlapFactor; %Коэффициент перекрытия границ уровней
        AccuracySpectrum; %Точность аппроксимации спектров
        
        %Выходные переменные
        Accel; %Ускорения
        Displacement; %Перемещения
        PartsDisplacement; %Фрагментированные перемещения
        PartsAccelGlued; %Склееные по уровням ускорения
        FrequencyAccelGlued; %Частоты склееных ускорений
        SpectrumAccelGluedVisualize; %Поверхность спектра ускорений
        PartsMonotoneAccelGlued; %Склейка монотонных фрагментов
        FrequencyMonotoneAccelGlued; %Частоты скленных монотонных фрагментов
        SpectrumMonotoneAccelGluedVisualize; %Поверхность спектра монотонных фрагментов
        Time; %Время
        LevelsNumb; %Число уровней
        LineLevels; %Линии уровней
        IndexLineLevels; %Индексы среднего уровеня
        DisplacementApprox; %Аппроксимированные перемещения
        DisplacementApproxDerivative; %Аппроксимированные производные от перемещений
        PartsExpAccel; %Экспонециально затухающие фрагменты сигнала
        PartsExpAccelGlued; %Склейка экспонециально затухающих фрагментов сигнала
        LimitsExpAccel; %Лимитирующие пределы затухания
        SpectrumExpAccelGluedVisualize; %Поверхность спектра экспонециально затухающего сигнала
        FrequencyExpAccelGlued; %Частоты для поверхности спектра экспонециального затухающего сигнала
        TableDecrementVisualize; %Таблица декрементов затухания по пикам
        PartsAccelApproxSpline; %Аппроксимирующие сплайны для каждого пика
        NormalizeMode; %Режим нормировки склеек
        
        %Рабочие переменные
        InputPathName = {}; %Директория временных сигналов
        InputFullFile = {}; %Имена файлов с директорией
        PlotedName = {}; %Имена построенных функций
        IndentCommonPlot = 1; %Идентификатор построения в одних осях
        MinLevelsStep; %Минимальная высота уровня
        CurrentLevels; %Индексы текущих уровней
        SpectrumAccelGluedVisualizeFix; %Усеченная поверхность спектра
        FrequencyAccelGluedFix; %Усеченные частоты склееных ускорений
        LastPath = ''; %Последняя выбранная директория
        SpectrumExpAccelGluedVisualizeFix; %Усеченная поверхность спектра экспонециально затухающих фрагментов
        FrequencyExpAccelGluedFix; %Усеченные частоты склееных экспонециально затухающих фрагментов
        SpectrumMonotoneAccelGluedVisualizeFix; %Усеченная поверхность спектра монотонных фрагментов
        FrequencyMonotoneAccelGluedFix; %Усеченные частоты монотонных склеек
        LastDir = ''; %Последняя директория для сохранения
        ControlLevelNumb; %Номер опорного уровня
        SpectrumAccelGluedVisualizeControlFix; %Усеченная поверхность спектра (контроль)
        SpectrumExpAccelGluedVisualizeControlFix; %Усеченная поверхность спектра экспонециально затухающих фрагментов (контроль)
        SpectrumMonotoneAccelGluedVisualizeControlFix; %Усеченная поверхность спектра монотонных фрагментов (контроль)
        CovarianceAccel = []; %Ковариация поверхности спектра ускорений
        CovarianceMonotone = {}; %Ковариация спектра монотонных склеек
        CovarianceExpAccel = []; %Ковариация поверхности спектра затухающих ускорений
        SampleRate; %Частота дискретизации сигнала
        RegressionInformationExpAccel = [];
        RegressionInformationMonotone = {};
        RegressionInformationAccel = []; %Информация о регрессии по сигналам
        %Спектр и частоты исходных ускорений
        SpectrumAccelInputFull; SpectrumAccelInputFullFix;
        FrequencyAccelInputFull; FrequencyAccelInputFullFix;
        %Склейки
        SpectrumAccelInputGlued; SpectrumAccelInputGluedFix;
        FrequencyAccelInputGlued; FrequencyAccelInputGluedFix;
        %По склейке монотонных фрагментов
        SpectrumAccelInputMonotone; SpectrumAccelInputMonotoneFix;
        FrequencyAccelInputMonotone; FrequencyAccelInputMonotoneFix;
        %По склейке экспонециально затухающих
        SpectrumAccelInputExp; SpectrumAccelInputExpFix;
        FrequencyAccelInputExp; FrequencyAccelInputExpFix;
        AngleCoeffUI = []; %Структура для полей коэффициентов
        MeanAngle = []; %Средний угол инклинометра
        InclinAccel = []; %Ускорения с учетом отклонения
    end
    
    methods (Access = private)
        
        function CheckParameters(app)
            %Проверка заполнения поля с исходными файлами
            if ~strcmp(app.EditAccelName.Value,'')
                app.ButtonCalculate.Enable = 'on';
            else
                app.ButtonCalculate.Enable = 'off';
                app.ButtonCalculate.Tag = 'Bad';
            end
        end
        
        
        function CommonPlotFig1(app, IndexPlot) %Общее построения графиков в одних осях
            Fig1 = figure(1);
            Fig1.Name = 'Построение выбранных графиков в одних осях';
            hold on; %Построение графиков в одних осях
            grid on; %Масштабная сетка
            for i = 1:length(IndexPlot)
                if CheckPloted(app, app.InputFullFile{IndexPlot(i)})
                    [~,~,Signal{i}] = ReadInput(app.InputFullFile{IndexPlot(i)},app.EditStartReadNumb.Value);
                    NamePlotCorrect = strrep(app.ListBoxInput.Items{IndexPlot(i)},'_','-');
                    if strcmp(app.ButtonGroupMultiPlotSettings.SelectedObject.Text,'Приведение к нулю')
                        Signal{i} = Signal{i} - Signal{i}(1);
                    end
                    if strcmp(app.ButtonGroupMultiPlotSettings.SelectedObject.Text,'Линейная корректировка')
                        Signal{i} = LineCorrect((1:length(Signal{i}))', Signal{i});
                    end
                    plot(Signal{i},'DisplayName',NamePlotCorrect);
                    app.PlotedName = [app.PlotedName; app.InputFullFile{IndexPlot(i)}];
                end
            end
            legend('show');
        end
        
        function IndexSelected = GetIndexSelFiles(app,DeleteMode)
            ItemsSelectedFileName = app.ListBoxInput.Value; %Имена выбранных файлов
            if isempty(ItemsSelectedFileName)
                IndexSelected = 0;
                return;
            end
            LengthListbox = length(app.ListBoxInput.Items);
            LengthSelected = length(ItemsSelectedFileName);
            for i = 1:LengthSelected %Цикл по всем именам
                for j = 1:LengthListbox %Удаление по всем повторам
                    if strcmp(ItemsSelectedFileName{i},app.ListBoxInput.Items{j})
                        if DeleteMode %Режим удаление выбранных элементов
                            app.ListBoxInput.Items(j) = []; %Удаляем имя файла
                            app.InputPathName(j) = []; %Удаляем путь к файлу
                            app.InputFullFile(j) = []; %Удаляем полный путь к файлу
                            IndexSelected = 0;
                        else
                            IndexSelected(i) = j;
                        end
                        break %Выходим из цикла
                    end
                end
            end
        end
        
        
        function Result = CheckPloted(app, FileName) %Проверка построения функции
            Result = 1;
            for i = 1:length(app.PlotedName)
                if strcmp(app.PlotedName{i},FileName)
                    Result = 0;
                    break
                end
            end
        end
        
        function IndexSelected = GetIndexSelMode(app)
            ItemsSelectedFileName = app.ListBoxShowFigure.Value; %Имена выбранных файлов
            LengthListbox = length(app.ListBoxShowFigure.Items);
            LengthSelected = length(ItemsSelectedFileName);
            for i = 1:LengthSelected %Цикл по всем именам
                for j = 1:LengthListbox %Удаление по всем повторам
                    if strcmp(ItemsSelectedFileName{i},app.ListBoxShowFigure.Items{j})
                        IndexSelected(i) = j;
                        break %Выходим из цикла
                    end
                end
            end
        end
        
        
        function UpdateCalcParam(app,ReadMode,FileNameSignal) %Обновление параметров расчёта
            switch ReadMode
                case 'Accel'
                    [~,TechnicalDataAccelTemp,AccelTemp] = ReadInput(FileNameSignal,app.EditStartReadNumb.Value); %Считывание файла ускорений
                    SampleRateTemp = str2double(TechnicalDataAccelTemp{end - 2}); %Частота дискретизации
                    AccelTemp = AccelTemp - AccelTemp(1); %Приведение ускорений к нулевой линии
                    [AccelTemp, ~] = PeakFilter(AccelTemp,[]); %Удаление выбросов из ускорений
                    TimeTemp = (1:length(AccelTemp))'; %Массив времени
                    DisplacementTemp = DoubleIntegral(TimeTemp, AccelTemp, app.EditCutOffFrequency.Value, SampleRateTemp); %Получение перемещений двойным интегрированием ускорений
                    %Выставление предельного значения частоты среза
                    app.EditCutOffFrequency.Limits = [0 SampleRateTemp/200];
                    %Выставляем пределы выбора частот для выделения экспонециального затухания
                    app.EditFreqDecrement.Limits = [1 SampleRateTemp/200];
                case 'Displacement'
                    [~,TechnicalDataDisplacementTemp,DisplacementTemp] = ReadInput(FileNameSignal,app.EditStartReadNumb.Value);
                    DisplacementTemp = DisplacementTemp - DisplacementTemp(1); %Приведение к нулю
                    TimeTemp = (1:length(DisplacementTemp))'; %Массив времени
            end
            if app.CheckBoxCorrectDisplacement.Value %Проверка режима коррекции линейного дрейфа
                %Отсечение низкочастотной части сигнала перемещений
                DisplacementTemp = LineCorrect(TimeTemp, DisplacementTemp);%Линейная корректировка сигнала
            end
            [DisplacementApproxTemp, DisplacementApproxDerivativeTemp] = ApproxSpline(TimeTemp,DisplacementTemp,app.EditAccuracy.Value,1); %Аппроксимация B-сплайнами
            if strcmp(ReadMode,'Displacement') && isempty(app.EditAccelName.Value) %Создание ускорений из перемещений
                %Получение ускорений из перемещений
                QuasiAccel = DisplacementTemp - DisplacementApproxTemp; %Новые ускорения
                %Выгрузка результатов
                PathTempIndex = strfind(app.FileNameDisplacement, app.EditDisplacementName.Value); %Выделяем путь
                PathQuasiAccel = app.FileNameDisplacement(1:PathTempIndex-1); %Путь по перемещениям
                NameQuasiAccel = strcat(app.EditDisplacementName.Value(1:strfind(app.EditDisplacementName.Value,'.txt') - 1),'-Ускорения'); %Убираем txt + ускорения
                OutputSaveTxt(QuasiAccel,strcat(NameQuasiAccel),TechnicalDataDisplacementTemp,PathQuasiAccel,'Ускорения, полученные из перемещений');
                %Вносим новые ускорения в ListBox
                PathQuasiAccel = strcat(PathQuasiAccel,'Результаты','\Ускорения, полученные из перемещений'); %Путь сохраненного файла
                NameQuasiAccel = strcat(NameQuasiAccel,'.txt'); %Имя сохраненного файла
                app.ListBoxInput.Items{end + 1} = NameQuasiAccel; %Запись названий файлов
                app.InputPathName{end + 1} = PathQuasiAccel; %Запись директорий файлов
                app.InputFullFile{end + 1} = fullfile(PathQuasiAccel,NameQuasiAccel); %Полный путь к файлу
            end
            %Корректируем минимальное значение высоту уровней
            app.MinLevelsStep = ceil(max(abs(DisplacementApproxDerivativeTemp))); %Минимальная высота уровня в силу симметрии (по сплайнам)
            Nopt = 6; %Оптимальное для отображения число уровней
            app.EditLevelsStep.Limits = [0 Inf]; %Освобождение границ перед вставкой
            app.EditLevelsStep.Value = ceil(abs(max(DisplacementApproxTemp)-min(DisplacementApproxTemp))/Nopt);
            if app.EditLevelsStep.Value < app.MinLevelsStep
                app.EditLevelsStep.Value = app.MinLevelsStep;
            end
            app.EditLevelsStep.Limits = [app.MinLevelsStep Inf]; %Пределы ввода высоты уровня
            %Находим пределы изменения сигнала и записываем их в label
            formatSpec = '%.0f/%.0f'; %Формат записи чисел
            app.LabelMaxMin.Text = ['Min/Max: ' num2str([min(DisplacementApproxTemp) max(DisplacementApproxTemp)],formatSpec)];
        end
        
        function FindDifferenceSurf(app) %Вычисление разницы поверхностей для заданного опорного уровня
            app.ControlLevelNumb = find(app.CurrentLevels == app.SpinnerControlLevelNumb.Value);
            %Контрольная поверхность спектра ускорений
            TempColumn = app.SpectrumAccelGluedVisualizeFix(:,app.ControlLevelNumb);
            app.SpectrumAccelGluedVisualizeControlFix(:,1:end) = app.SpectrumAccelGluedVisualizeFix(:,1:end) - TempColumn;
            app.SpectrumAccelGluedVisualizeControlFix(:,app.ControlLevelNumb) = TempColumn;
            %Контрольная поверхность затухающих фрагментов
            TempColumn = app.SpectrumExpAccelGluedVisualizeFix(:,2);
            app.SpectrumExpAccelGluedVisualizeControlFix(:,1) = app.SpectrumExpAccelGluedVisualizeFix(:,1) - TempColumn;
            %Контрольная поверхность монотонных склеек
            for s = 1:3 %[Increase, Neutral, Decrease]
                TempColumn = app.SpectrumMonotoneAccelGluedVisualizeFix{s}(:,2);
                app.SpectrumMonotoneAccelGluedVisualizeControlFix{s}(:,1:2:3) = app.SpectrumMonotoneAccelGluedVisualizeFix{s}(:,1:2:3) - TempColumn;
            end
        end
        
        function [BaseLevel,ShowLevel] = CorrectNumbLevel(app,Mode)
            %Проверка совпадения номера списка для разных поверхностей
            switch Mode
                case 'Monotone' %Монотонные фрагменты
                    %Base
                    switch app.CurrentLevels(app.ControlLevelNumb)
                        case -1 %Убывающие фрагменты
                            BaseLevel = 1;
                        case 0 %Нулевые фрагменты
                            BaseLevel = 2;
                        case 1 %Возрастающие фрагменты
                            BaseLevel = 3;
                        otherwise %Если уровень не в списке
                            BaseLevel = 2;
                    end
                    %Show
                    switch app.CurrentLevels(app.ShowNumb)
                        case -1 %Убывающие фрагменты
                            ShowLevel = 1;
                        case 0 %Нулевые фрагменты
                            ShowLevel = 2;
                        case 1 %Возрастающие фрагменты
                            ShowLevel = 3;
                        otherwise %Если уровень не в списке
                            ShowLevel = 2;
                    end
                case 'Exp' %Экспонециально затухающие фрагменты
                    %Base
                    switch app.CurrentLevels(app.ControlLevelNumb)
                        case -1 %Убывающие фрагменты
                            BaseLevel = 1;
                        case 0 %Нулевые фрагменты
                            BaseLevel = 2;
                        otherwise %Если уровень не в списке
                            BaseLevel = 2;
                    end
                    %Show
                    switch app.CurrentLevels(app.ShowNumb)
                        case -1 %Убывающие фрагменты
                            ShowLevel = 1;
                        case 0 %Нулевые фрагменты
                            ShowLevel = 2;
                        otherwise %Если уровень не в списке
                            ShowLevel = 2;
                    end
            end
        end
        
        %Усечение спектра и частоты по заданным граничным частотам
        function [FrequencyFix, SpectrumFix] = FindFixSpectrum(app, Frequency, Spectrum)
            IndexFix = find(Frequency >= app.SpinnerFixSpectrumLow.Value & Frequency <= app.SpinnerFixSpectrumHigh.Value); %Находим номера частот >= частоты среза
            FrequencyFix = Frequency(IndexFix); %Частоты по найденным номерам
            SpectrumFix = Spectrum(IndexFix,:); %Спектр по заданным номерам
        end
        
        %Создание таблицы спектр - частота
        function Tab = CreateFreqSpectTab(app, Spectrum, Frequency)
            LevelsNum = size(Spectrum,2);
            if size(Spectrum,1) < 3 %Ограничение функции findpeaks
                for i = 1:LevelsNum
                    Tab{i} = [];
                end
                return;
            end
            for i = 1:LevelsNum
                [pks, locs] = findpeaks(Spectrum(:,i), Frequency); %Отыскание локальных максимумов на заданном интервале
                Tab{i} = [locs, pks];
            end
        end
        
        function ComparePlot(app)
            IndexPlot = GetIndexSelFiles(app, 0); %Получить номера выбранных элементов
            MinLengthSignals = Inf; %Инициализация минимальной длины сигнала
            PairNumb = 1; %Число пар для одновременного отображения
            if length(IndexPlot) >= 2 && mod(length(IndexPlot), 2) == 0   %Проверка парности сигналов
                Fig2 = figure(2);
                Fig2.Name = 'Сравнение выбранных сигналов';
                if PairNumb > 1
                    hold on; %Построение графиков в одних осях
                end
                for i = 1:2*PairNumb %Запись первой пары сигналов
                    [~, ~, Signals{i}] = ReadInput(app.InputFullFile{IndexPlot(i)},app.EditStartReadNumb.Value);
                    NamePlotCorrect{i} = strrep(app.ListBoxInput.Items{IndexPlot(i)},'_','-'); %Корректные для отображения названия файлов
                    if length(Signals{i}) < MinLengthSignals %Запись минимальной длины сигнала
                        MinLengthSignals = length(Signals{i});
                    end
                end
                %Срез сигналов по длине наименьшего
                for i = 1:length(Signals)
                    Signals{i} = Signals{i}(1:MinLengthSignals);
                end
                %Построение пар сигналов
                for i = 1:PairNumb
                    FunX = Signals{2*PairNumb-1}; FunY = Signals{2*PairNumb}; %Y1 и Y2 для сравнения
                    LablX = NamePlotCorrect{2*PairNumb-1}; LablY = NamePlotCorrect{2*PairNumb}; %Названия сигналов
                    Colors = jet(length(FunX)); %Получение RGB матрицы цветов
                    Colors = Colors(end:-1:1,:); %Переворот матрицы цветов
                    colormap(Colors); %Выбор цветовой шкалы
                    scatter(FunX, FunY,[], Colors, '*'); %Построение цветного распределения
                    cb = colorbar; %Цветовая шкала
                    cb.Label.String = 'Относительная длина сигнала';
                    xlabel(LablX); ylabel(LablY); %Названия осей
                end
                grid on; %Масштабная сетка
                
            end
        end
        
    end
    methods (Access = public)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ScreenSize = get(0, 'ScreenSize'); %Get screen size
            ScreenWidth = app.ScreenSize(3); ScreenHeight = app.ScreenSize(4); %Размеры экрана
            UiWidth = app.Main.Position(3); UiHeight = app.Main.Position(4); %Размеры приложения
            app.Main.Position = [ScreenWidth/2 - UiWidth/2 ScreenHeight/2 - UiHeight/2 UiWidth UiHeight]; %Размещение по центру экрана
        end

        % Button pushed function: ButtonAdd
        function ButtonAddPushed(app, event)
            [FileName PathName filterindex] = uigetfile('*.txt','Выберите набор временных сигналов','MultiSelect','on', app.LastPath); %Чтение исходных файлов
            if PathName ~= 0 %Если выбор был сделан
                app.LastPath = PathName; %Запоминаем последний выбранный путь
                IndexStartWrite = length(app.ListBoxInput.Items); %Индекс начала записи
                if iscell(FileName) %Проверка MultiSelect
                    LengthAdd = length(FileName); %Число файлов
                    for i = 1:LengthAdd %Цикл по числу файлов
                        app.ListBoxInput.Items{IndexStartWrite + i} = FileName{i}; %Запись названий файлов
                        app.InputPathName{IndexStartWrite + i} = PathName; %Запись директорий файлов
                        app.InputFullFile{IndexStartWrite + i} = fullfile(PathName, FileName{i}); %Полный путь к файлу
                    end
                else %Запись одного файла
                    app.ListBoxInput.Items{IndexStartWrite + 1} = FileName; %Запись названий файлов
                    app.InputPathName{IndexStartWrite + 1} = PathName; %Запись директорий файлов
                    app.InputFullFile{IndexStartWrite + 1} = fullfile(PathName,FileName); %Полный путь к файлу
                end
            end
            app.Main.Visible = 'off'; app.Main.Visible = 'on'; %Возвращаем фокус на главное окно
        end

        % Button pushed function: ButtonCalculate
        function ButtonCalculatePushed(app, event)
            %Обнуление полей перед запуском расчёта
            app.SpinnerShowNumb.Value = 0; %Обнуление уровня для отображения
            app.CurrentLevels = 0; %Обнуление текущей выборки уровней
            app.MeanAngle = []; %Обнуление угла инклинометра
            app.InclinAccel = []; %Обнуление ускорений с учетом угла
            for s = 1:3 
                app.CovarianceMonotone{s} = {}; %Обнуление ковариации монотонных склеек по канала
                app.RegressionInformationMonotone{s} = {}; %Обнуление информации о регрессии
            end 
            
            %Запись переменных для расчёта
            app.StartReadNumb = app.EditStartReadNumb.Value; %Номер отсчетной строки
            app.LevelsStep = app.EditLevelsStep.Value; %Режим приведения склеек по длине
            app.Accuracy = app.EditAccuracy.Value; %Точность аппроксимацииы
            app.CutProcent = app.EditCutProcent.Value; %Процент усечения фрагментов
            app.CorrectDisplacement = app.CheckBoxCorrectDisplacement.Value; %Корректировка линейного дрейфа оборудования
            app.CutOffFrequency = app.EditCutOffFrequency.Value; %Частота среза, Гц
            app.CorrectLengthMode = app.DropDownCorrectLength.Value; %Режим приведения склеек по длине
            app.AccuracyExp = app.EditAccuracyExp.Value; %Точность выделения экспонециального затухания
            app.LimExpAccelLevel = app.EditLimExpAccelLevel.Value; %Предельное значение диапазона ускорений
            app.FreqDecrement = app.EditFreqDecrement.Value; %Частота выделения декремента затухания
            app.DepthGluing = app.EditDepthGluing.Value; %Глубина прохода для склейки
            app.ModelApprox = app.DropDownModelApprox.Value; %Модель выделения декремента затуханий
            app.OverlapFactor = app.EditOverlapFactor.Value; %Смещение границы уровней в долях
            app.AccuracySpectrum = app.EditAccuracySpectrum.Value; %Точность аппроксимации спектров
            app.NormalizeMode = app.CheckBoxNormalizeMode.Value; %Режим нормировки склеек
            
            %Корректировка значений для вызова
            switch app.CorrectLengthMode %Режим приведения по длине
                case 'Нет'
                    app.CorrectLengthMode = 'No';
                case 'Исходной'
                    app.CorrectLengthMode = 'Input';
                case 'Максимальной'
                    app.CorrectLengthMode = 'Maximum';
            end
            
            %Вызов расчётной функции
            SaveColor = app.ButtonCalculate.BackgroundColor; %Сохранение цвета кнопки
            app.ButtonCalculate.BackgroundColor = 'green'; %Отображение начала расчёта
            try
                Result = ProofAccel(app.FileNameDisplacement,... %Имя файла c перемещениями
                    app.FileNameAccel,... %Имя файла c ускорениями
                    app.StartReadNumb,... %Номер отсчётной строки для перемещений
                    app.StartReadNumb,... %Номер отсчётной строки для ускорений
                    app.LevelsStep,...%Число уровней сигнала
                    app.Accuracy,...%Точность аппроксимации
                    app.CutProcent,... %Процент усечения фрагментов
                    app.CorrectDisplacement,... %Корректировка линейного дрейфа оборудования
                    app.CutOffFrequency,... %Частота среза, Гц
                    app.CorrectLengthMode,... %Режим приведения по длине
                    app.AccuracyExp,... %Точность выделения экспонециального затухания
                    app.LimExpAccelLevel,... %Предельное значение диапазона ускорений
                    app.FreqDecrement,... %Частота выделения декремента затуханий
                    app.DepthGluing,... %Глубина прохода для склейки
                    app.ModelApprox,... %Модель выделения декремента затуханий
                    app.OverlapFactor,... %Коэффициент перекрытия границ уровней
                    app.AccuracySpectrum,... %Точность аппроксимации спектров
                    app.NormalizeMode); %Режим нормировки склеек
                
            catch ME %Проверка расчётных ошибок
                ExceptionFunction = ME.stack.name; %Имя функции, в которой возникло исключение
                switch ExceptionFunction
                    case 'FindExpDecrease' %Проблема с выделением затухания
                        errordlg(strcat('Измените предельное значение нулевых фрагментов'),'Ошибка расчёта');
                    case 'FixNormalizeDerivative' %Пустой уровень
                        errordlg(strcat('Измените высоту уровней'),'Ошибка расчёта');
                    otherwise
                        errordlg(strcat('В ходе проведения расчёта возникла ошибка: ',ME.message,'Идентификатор функции: ',ExceptionFunction),'Ошибка расчёта'); %Отладка
                end
                app.ButtonCalculate.BackgroundColor = SaveColor;
                return;
            end
            app.ButtonCalculate.BackgroundColor = SaveColor; %Отображение конца расчёта
            %Запись результатов расчёта
            app.Accel = Result{1}; %Ускорения
            app.Displacement = Result{2}; %Перемещения
            app.PartsDisplacement = Result{3} ; %Фрагментированные перемещения
            app.PartsAccelGlued = Result{4}; %Склееные по уровням ускорения
            app.FrequencyAccelGlued = Result{5}; %Частоты склееных ускорений
            app.SpectrumAccelGluedVisualize = Result{6}; %Поверхность спектра ускорений
            app.PartsMonotoneAccelGlued = Result{7}; %Склейка монотонных фрагментов
            app.FrequencyMonotoneAccelGlued = Result{8}; %Частоты скленных монотонных фрагментов
            app.SpectrumMonotoneAccelGluedVisualize = Result{9}; %Поверхность спектра монотонных фрагментов
            app.Time = Result{10}; %Время
            app.LevelsNumb = Result{11}; %Число уровней сигнала
            app.LineLevels = Result{12}; %Линии уровней
            app.DisplacementApprox = Result{13}; %Аппроксимированные перемещения
            app.DisplacementApproxDerivative = Result{14}; %Аппроксимированные производные от перемещений
            app.PartsExpAccel = Result{15}; %Экспонециально затухающие фрагменты сигнала
            app.PartsExpAccelGlued = Result{16}; %Склейка экспонециально затухающих фрагментов сигнала
            app.LimitsExpAccel = Result{17}; %Лимитирующие пределы затухания
            app.SpectrumExpAccelGluedVisualize = Result{18}; %Поверхность спектра экспонециально затухающего сигнала
            app.FrequencyExpAccelGlued = Result{19}; %Частоты для поверхности спектра экспонециального затухающего сигнала
            app.TableDecrementVisualize = Result{20}; %Таблица декрементов затухания по пикам
            app.PartsAccelApproxSpline = Result{21}; %Аппроксимирующие сплайны для каждого пика
            
            %Настройка программных компонентов
            app.ButtonCalculate.Tag = 'Ok'; %Результаты расчёта получены
            app.ButtonSave.Enable = 'on'; %Активация возможности сохранения
            app.CurrentLevels = app.LineLevels(:,3); %Номера уровней (от нуля)
            app.FrequencyAccelGlued(1) = []; %Удаление нулевой частоты
            if app.CurrentLevels(1) ~= app.CurrentLevels(end)
                app.SpinnerShowNumb.Limits = [app.CurrentLevels(1), app.CurrentLevels(end)]; %Выставление пределов выбора уровней
            end
            app.SpinnerFixSpectrumLow.Limits = [app.FrequencyAccelGlued(1), app.FrequencyAccelGlued(end)]; %Выставление пределов выбора частоты среза для поверхности
            app.SpinnerFixSpectrumHigh.Limits = [app.FrequencyAccelGlued(1), app.FrequencyAccelGlued(end)]; %Выставление пределов выбора частоты среза для поверхности
            app.SpinnerFixSpectrumLow.Value = app.FrequencyAccelGlued(1); %Выставление минимальной нижней границы среза
            app.SpinnerFixSpectrumHigh.Value = app.FrequencyAccelGlued(end); %Выставление максимальной верхней границы среза
            app.ShowNumb = find(app.CurrentLevels == app.SpinnerShowNumb.Value); %Находим соответствие между порядковым и отнулевым номерам
            if app.CurrentLevels(1) ~= app.CurrentLevels(end)
                app.SpinnerControlLevelNumb.Limits = [app.CurrentLevels(1), app.CurrentLevels(end)]; %Выставление пределов выбора уровней
            end
            app.ControlLevelNumb = find(app.CurrentLevels == app.SpinnerControlLevelNumb.Value); %Находим соответствие между порядковым и отнулевым номерам
            %Нахождение частоты дискретизации сигнала
            [~,TechnicalDataAccelTemp,~] = ReadInput(app.FileNameAccel,app.EditStartReadNumb.Value); %Считывание файла ускорений
            app.SampleRate = str2double(TechnicalDataAccelTemp{end - 2}); %Частота дискретизации
            
            %Нахождение спектров исходного сигнала
            AccelForSpec = app.Accel; AccelForSpec = AccelForSpec - mean(AccelForSpec); %Предобработка: к среднему
            if app.NormalizeMode %+ нормализация
                AccelForSpec = AccelForSpec/max(abs(AccelForSpec));
            end
            CellForSpec{1} = zeros(length(AccelForSpec),3); CellForSpec{1}(:,2) = AccelForSpec; %Формирование ячеистой структуры
            [app.SpectrumAccelInputFull, app.FrequencyAccelInputFull] = FindSpectrum(CellForSpec, app.SampleRate, 0, 'Welch', app.AccuracySpectrum); %Нахождение спектра исходных ускорений
            %Аппроксимация полного на сетках сигналов для сравненения
            %На склееных ускорениях
            if length(app.FrequencyAccelInputFull) ~= length(app.FrequencyAccelGlued)
                app.FrequencyAccelInputGlued = app.FrequencyAccelGlued;
                app.SpectrumAccelInputGlued = pchip(app.FrequencyAccelInputFull, app.SpectrumAccelInputFull, app.FrequencyAccelInputGlued);
            else
                app.FrequencyAccelInputGlued = app.FrequencyAccelInputFull;
                app.SpectrumAccelInputGlued = app.SpectrumAccelInputFull;
            end
            %На монотонных склейках
            for s = 1:3
                if length(app.FrequencyAccelInputFull) ~= length(app.FrequencyMonotoneAccelGlued{s})
                    app.FrequencyAccelInputMonotone{s} = app.FrequencyMonotoneAccelGlued{s};
                    app.SpectrumAccelInputMonotone{s} = pchip(app.FrequencyAccelInputFull, app.SpectrumAccelInputFull, app.FrequencyAccelInputMonotone{s});
                else
                    app.FrequencyAccelInputMonotone{s} = app.FrequencyAccelInputFull;
                    app.SpectrumAccelInputMonotone{s} = app.SpectrumAccelInputFull;
                end
            end
            %На затухающих склейках
            if length(app.FrequencyAccelInputFull) ~= length(app.FrequencyExpAccelGlued)
                app.FrequencyAccelInputExp = app.FrequencyExpAccelGlued;
                app.SpectrumAccelInputExp = pchip(app.FrequencyAccelInputFull, app.SpectrumAccelInputFull, app.FrequencyAccelInputExp);
            else
                app.FrequencyAccelInputExp = app.FrequencyAccelInputFull;
                app.SpectrumAccelInputExp = app.SpectrumAccelInputFull;
            end
            %Поиск экстремальной частоты
            [~,Index] =  max(abs(app.SpectrumExpAccelGluedVisualize)); %Индексы макс. по всем столбцам
            Index = max(Index); %Индекс максимального элемента
            app.LabelMaxFreq.Text = num2str(app.FrequencyExpAccelGlued(Index),'%.2f');
            SpinnerFixSpectrumLowValueChanged(app, event); %Вызов усечения поверхности по заданной частоте
            %Расчёт параметров инклинометра
            SignalNumParam = FindNumString(TechnicalDataAccelTemp{10}); %Получение канала, баланса и температуры
            [~,~,AccelTempNonZero] = ReadInput(app.FileNameAccel,app.EditStartReadNumb.Value); %Считывание файла ускорений
            if SignalNumParam(1) == 3 || SignalNumParam(1) == 4 %Нахождение среднего угла инклинометра
                ButtonAngleCoeffcientsPushed(app, event); %Вызов окна значений коэффициентов
                for i = 1:size(app.AngleCoeffUI.UITableCoefficients.Data,1)
                    for j = 1:size(app.AngleCoeffUI.UITableCoefficients.Data,2)
                        TableCoeff(i,j) = str2double(app.AngleCoeffUI.UITableCoefficients.Data{i,j}); %Перезапись коэффиициентов
                    end
                end
                if SignalNumParam(1) == 3
                    app.MeanAngle = (TableCoeff(1,1) - mean(AccelTempNonZero) - TableCoeff(1,4)*(TableCoeff(1,3) - SignalNumParam(2)))*TableCoeff(1,2);
                    for i = 1:length(AccelTempNonZero)
                        app.InclinAccel(i,1) = (TableCoeff(1,1) - AccelTempNonZero(i) - TableCoeff(1,4)*(TableCoeff(1,3) - SignalNumParam(2)))*TableCoeff(1,2);
                    end
                else
                    app.MeanAngle = (TableCoeff(2,1) - mean(AccelTempNonZero) - TableCoeff(2,4)*(TableCoeff(2,3) - SignalNumParam(2)))*TableCoeff(2,2);
                    for i = 1:length(AccelTempNonZero)
                        app.InclinAccel(i,1) = (TableCoeff(2,1) - AccelTempNonZero(i) - TableCoeff(2,4)*(TableCoeff(2,3) - SignalNumParam(2)))*TableCoeff(2,2);
                    end
                end
                close(app.AngleCoeffUI.AngleCoeff); %Закрытие окна значений коэффициентов
            end
        end

        % Button pushed function: ButtonClearChoise
        function ButtonClearChoisePushed(app, event)
            app.EditAccelName.Value = ''; %Очистка выбранного файла ускорений
            app.EditDisplacementName.Value = ''; %Очистка выбранного файла перемещений
            app.FileNameDisplacement = ''; %Очистка переменной перемещений
            app.FileNameAccel = ''; %Очистка переменной ускорений
            CheckParameters(app); %Проверям заполнение полей исходных данных
        end

        % Button pushed function: ButtonClear
        function ButtonClearPushed(app, event)
            app.ListBoxInput.Items = {}; %Очистка ListBox
            app.InputPathName = {}; %Очистка ListBox
            app.InputFullFile = {}; %Очистка ListBox
            app.LabelMaxMin.Text = ''; %Очистка поля с отображением пределов перемещений
            app.LabelMaxFreq.Text = ''; %Очистка поля с максимальной частотой
            close all; %Очистка окна построений
            app.PlotedName = {}; %Очищаем переменную с имена построенных графиков
            app.EditAccelName.Value = ''; %Очистка поля ускорений
            app.EditDisplacementName.Value = ''; %Очистка поля перемещений
            app.FileNameDisplacement = ''; %Очистка переменной перемещений
            app.FileNameAccel = ''; %Очистка переменной ускорений
            CheckParameters(app); %Проверям заполнение полей исходных данных
            app.ButtonSave.Enable = 'off'; %Блокировка кнопки сохранения
        end

        % Button pushed function: ButtonDefineAccelName
        function ButtonDefineAccelNamePushed(app, event)
            if ~isempty(app.ListBoxInput.Value)
                app.EditAccelName.Value = app.ListBoxInput.Value{1}; %Записать наименьшее из выбранных
                IndexSelected = GetIndexSelFiles(app,0); %Получаем выбранные номера элементов
                app.FileNameAccel = fullfile(app.InputPathName{IndexSelected(1)},app.ListBoxInput.Value{1}); %Присваиваем по первому номеру
            else
                app.EditAccelName.Value = ''; %Очистка поля
            end
            if ~isempty(app.EditAccelName.Value) && isempty(app.EditDisplacementName.Value) %Проверка заполнения полей
                UpdateCalcParam(app,'Accel',app.FileNameAccel); %Обновление параметров расчёта
            end
            CheckParameters(app); %Проверям заполнение полей исходных данных
        end

        % Button pushed function: ButtonDefineDisplacementName
        function ButtonDefineDisplacementNamePushed(app, event)
            if ~isempty(app.ListBoxInput.Value)
                app.EditDisplacementName.Value = app.ListBoxInput.Value{1}; %Записать наименьшее из выбранных
            else
                app.EditDisplacementName.Value = ''; %Очистка поля
            end
            IndexSelected = GetIndexSelFiles(app,0); %Получаем выбранные номера элементов
            app.FileNameDisplacement = fullfile(app.InputPathName{IndexSelected(1)},app.EditDisplacementName.Value); %Присваиваем по первому номеру
            %Оптимальная высота уровней
            if ~isempty(app.EditDisplacementName.Value) %Обновление параметров расчёта
                UpdateCalcParam(app,'Displacement',app.FileNameDisplacement);
            end
        end

        % Selection changed function: ButtonGroupMultiPlotSettings
        function ButtonGroupMultiPlotSettingsSelectionChanged(app, event)
            selectedButton = app.ButtonGroupMultiPlotSettings.SelectedObject;
            ListBoxInputValueChanged(app, event);
        end

        % Button pushed function: ButtonInfo
        function ButtonInfoPushed(app, event)
            IndexSelected = GetIndexSelFiles(app,0); %Индексы выбранных элементов
            if IndexSelected ~= 0 %При выборе элементов
                LengthSelected = length(IndexSelected); %Число выбранных элементов
                for i = 1:LengthSelected
                    [~,TempData,~] = ReadInput(app.InputFullFile{IndexSelected(i)},app.EditStartReadNumb.Value); %ЗАпись технических сведений
                    Help = Technical_data; %Вызываем окно справки
                    Help.FileName.Value = TempData; %Запись сведений в окно справки
                    Help.Label.Text = app.ListBoxInput.Items{IndexSelected(i)}; %Подзаголовок окна справки
                end
            end
        end

        % Button pushed function: ButtonLoadSettings
        function ButtonLoadSettingsPushed(app, event)
            [FileName,PathName,~] = uigetfile('*.txt','Выберите файл с настройками', app.LastDir); %Чтение исходных файлов
            if strcmp(FileName,'Settings.txt')
                [LoadSettings,~,~] = ReadInput(fullfile(PathName,FileName),1); %Считывание файла настроек
                %Разделение переменной и её значения
                for i = 1:size(LoadSettings,1)
                    ParamsValueIndex = strfind(LoadSettings{i,1},'=');
                    LoadSettings{i,2} = LoadSettings{i,1}(ParamsValueIndex + 2:end);
                    LoadSettings{i,1} = LoadSettings{i,1}(1:ParamsValueIndex - 2);
                end
                %Запись полученных данных в соответствующие поля
                try
                    app.EditLevelsStep.Value = str2num(LoadSettings{6,2}); %Шаг по уровням
                catch
                    msgbox({'Шаг по уровняем ниже минимального.' 'Выставлен минимальный шаг'},'Ошибка');
                    app.EditLevelsStep.Value = app.EditLevelsStep.Limits(1); %Минимальный шаг
                end
                app.EditAccuracy.Value = str2num(LoadSettings{7,2}); %Точность аппроксимации
                app.EditCutProcent.Value = str2num(LoadSettings{9,2}); %Процент усечения фрагментов
                CorrectLengthModeLocal = LoadSettings{10,2}; %Режим приведения склеек по длине
                switch CorrectLengthModeLocal %Режим приведения по длине
                    case 'No'
                        app.DropDownCorrectLength.Value = 'Нет';
                    case 'Input'
                        app.DropDownCorrectLength.Value = 'Исходной';
                    case 'Maximum'
                        app.DropDownCorrectLength.Value = 'Максимальной';
                end
                app.CheckBoxCorrectDisplacement.Value = str2num(LoadSettings{11,2}); %Корректировка линейного дрейфа оборудования
                app.EditCutOffFrequency.Value = str2num(LoadSettings{12,2}); %Частота среза, Гц
                app.EditAccuracyExp.Value = str2num(LoadSettings{13,2}); %Точность выделения экспонециального затухания
                app.EditLimExpAccelLevel.Value = str2num(LoadSettings{14,2}); %Предельное значение диапазона ускорений
                app.EditFreqDecrement.Value = str2num(LoadSettings{15,2}); %Частота выделения декремента затухания
                app.EditDepthGluing.Value = str2num(LoadSettings{16,2}); %Глубина прохода для склейки
                app.DropDownModelApprox.Value = LoadSettings{17,2}; %Модель выделения декрементов
                app.EditOverlapFactor.Value = str2num(LoadSettings{18,2}); %Коэффициент перекрытия границ уровней
                app.EditAccuracySpectrum.Value = str2num(LoadSettings{19,2}); %Точность аппроксимации спектров
                app.CheckBoxNormalizeMode.Value = str2num(LoadSettings{20,2}); %Режим нормирование склеек
            end
        end

        % Button pushed function: ButtonSave
        function ButtonSavePushed(app, event)
            [~,TechnicalDataTemp,~] = ReadInput(app.FileNameAccel, app.StartReadNumb); %Получение технических сведений
            TabTechnicalDataTemp = [TechnicalDataTemp; 'Frequency, Hz  ||  Amplitude'; ' ']; %Технические сведения для результирующей таблицы
            %Осуществление выбора директории сохранения
            if isempty(app.LastDir)
                app.LastDir = app.LastPath; %Начальная приближение директории для сохранения
            end
            app.LastDir = uigetdir(app.LastDir,'Выберите директорию для сохранения результатов расчёта');
            if app.LastDir == 0 %Обработка отмены сохранения
                app.LastDir = [];
                return;
            end
            SaveColor = app.ButtonCalculate.BackgroundColor; %Сохранение цвета кнопки
            app.ButtonSave.BackgroundColor = 'green'; %Отображение начала сохранения
            %Сохранение выходных файлов
            OutputSaveTxt(app.Accel,'Исходные ускорения',TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            OutputSaveTxt(app.Accel./max(abs(app.Accel)),'Исходные нормированные ускорения',TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            OutputSaveTxt(app.SpectrumAccelInputFullFix, 'Cпектр исходных ускорений', TechnicalDataTemp, app.LastDir, app.EditAccelName.Value);
            if ~isempty(app.MeanAngle) %Идентификация инклинометра
                OutputSaveTxt(app.MeanAngle, 'Cредний угол инклинометра', TechnicalDataTemp, app.LastDir, app.EditAccelName.Value);
                OutputSaveTxt(app.InclinAccel, 'Ускорения инклинометра', TechnicalDataTemp, app.LastDir, app.EditAccelName.Value);
            end
            TabTempAccelInput = CreateFreqSpectTab(app, app.SpectrumAccelInputFullFix, app.FrequencyAccelInputFullFix);
            TabTempAccelInputRatio = {sortrows([TabTempAccelInput{1}(:,1),TabTempAccelInput{1}(:,2)/max(TabTempAccelInput{1}(:,2))],2,'descend')}; %Таблица соотношения
            if ~isempty(TabTempAccelInput{1})
                OutputSaveXls(TabTempAccelInput,'0', 'Таблица спектра исходных ускорений', TabTechnicalDataTemp, app.LastDir, app.EditAccelName.Value);
                OutputSaveXls(TabTempAccelInputRatio,'0', 'Таблица соотношений спектра исходных ускорений', TabTechnicalDataTemp, app.LastDir, app.EditAccelName.Value);
            end
            for i = 1:length(app.CurrentLevels)
                OutputSaveTxt(app.PartsAccelGlued{i}(:,2), ['Склееный по уровню №' num2str(app.CurrentLevels(i)) ' сигнал ускорений'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            end
            if isempty(app.FileNameDisplacement) %Проверка режима считывания данных
                OutputSaveTxt(app.Displacement,'Отнулевые перемещения после двукратного интегрирования ускорений',TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            else
                OutputSaveTxt(app.Displacement,'Исходные перемещения',TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                OutputSaveTxt(app.Displacement./max(abs(app.Displacement)),'Исходные нормированные перемещения',TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            end
            for i = 1:length(app.CurrentLevels)
                OutputSaveTxt(app.PartsMonotoneAccelGlued{1}{i}(:,2),['Склееный по уровню №' num2str(app.CurrentLevels(i)) ' возрастающий сигнал ускорений'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                OutputSaveTxt(app.PartsMonotoneAccelGlued{2}{i}(:,2),['Склееный по уровню №' num2str(app.CurrentLevels(i)) ' нейтральный сигнал ускорений'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                OutputSaveTxt(app.PartsMonotoneAccelGlued{3}{i}(:,2),['Склееный по уровню №' num2str(app.CurrentLevels(i)) ' убывающий сигнал ускорений'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            end
            TabTempAccelGlued = CreateFreqSpectTab(app, app.SpectrumAccelGluedVisualizeFix, app.FrequencyAccelGluedFix);
            for i = 1:size(app.SpectrumAccelGluedVisualizeFix,2) %Сохранение поверхности по уровням
                if ~isempty(TabTempAccelGlued{i})
                    TabTempAccelGluedRatio{i} = sortrows([TabTempAccelGlued{i}(:,1),TabTempAccelGlued{i}(:,2)/max(TabTempAccelGlued{i}(:,2))],2,'descend');
                else
                    TabTempAccelGluedRatio{i} = [];
                end
                OutputSaveTxt(app.SpectrumAccelGluedVisualizeFix(:,i),['Cпектр ускорений уровня №' num2str(app.CurrentLevels(i))],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            end
            OutputSaveXls(TabTempAccelGlued,app.CurrentLevels,'Таблица спектра ускорений по уровням',TabTechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            OutputSaveXls(TabTempAccelGluedRatio,app.CurrentLevels,'Таблица соотношений спектра ускорений по уровням',TabTechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            OutputSaveTxt(app.DisplacementApprox,'Аппроксимированные перемещения',TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            OutputSaveTxt(app.DisplacementApproxDerivative,'Производные от аппроксимированных перемещений',TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            TabTempExp = CreateFreqSpectTab(app, app.SpectrumExpAccelGluedVisualizeFix, app.FrequencyExpAccelGluedFix);
            for i = 1:size(app.SpectrumExpAccelGluedVisualizeFix,2) %Сохранение поверхности по уровням
                if ~isempty(TabTempExp{i})
                    TabTempExpRatio{i} = sortrows([TabTempExp{i}(:,1), TabTempExp{i}(:,2)/max(TabTempExp{i}(:,2))],2,'descend');
                else
                    TabTempExpRatio{i} = [];
                end
                OutputSaveTxt(app.SpectrumExpAccelGluedVisualizeFix(:,i),['Cпектр затухающих склеек уровня №' num2str(i-2)],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            end
            OutputSaveXls(TabTempExp, [-1, 0], 'Таблица спектра затухающих склеек по уровням',TabTechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            OutputSaveXls(TabTempExpRatio, [-1, 0], 'Таблица соотношений спектра затухающих склеек по уровням',TabTechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            for s = 1:3 %[Increase, Neutral, Decrease]
                TabTempMonotone = CreateFreqSpectTab(app, app.SpectrumMonotoneAccelGluedVisualizeFix{s}, app.FrequencyMonotoneAccelGluedFix{s});
                for i = 1:size(app.SpectrumMonotoneAccelGluedVisualizeFix,2) %Сохранение поверхности по уровням
                    if ~isempty(TabTempMonotone{i})
                        TabTempMonotoneRatio{i} = sortrows([TabTempMonotone{i}(:,1), TabTempMonotone{i}(:,2)/max(TabTempMonotone{i}(:,2))],2,'descend');
                    else
                        TabTempMonotoneRatio{i} = [];
                    end
                    switch s 
                        case 1 
                            OutputSaveTxt(app.SpectrumMonotoneAccelGluedVisualizeFix{s}(:,i),['Cпектр возрастающих склеек уровня №' num2str(app.CurrentLevels(i))],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                        case 2
                            OutputSaveTxt(app.SpectrumMonotoneAccelGluedVisualizeFix{s}(:,i),['Cпектр нейтральных склеек уровня №' num2str(app.CurrentLevels(i))],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                        case 3
                            OutputSaveTxt(app.SpectrumMonotoneAccelGluedVisualizeFix{s}(:,i),['Cпектр убывающих склеек уровня №' num2str(app.CurrentLevels(i))],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                    end    
                end
                switch s
                    case 1
                        OutputSaveXls(TabTempMonotone,app.CurrentLevels,'Таблица спектра возрастающих склеек',TabTechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                        OutputSaveXls(TabTempMonotoneRatio,app.CurrentLevels,'Таблица соотношений спектра возрастающих склеек',TabTechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                    case 2
                        OutputSaveXls(TabTempMonotone,app.CurrentLevels,'Таблица спектра нейтральных склеек',TabTechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                        OutputSaveXls(TabTempMonotoneRatio,app.CurrentLevels,'Таблица соотношений спектра нейтральных склеек',TabTechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                    case 3
                        OutputSaveXls(TabTempMonotone,app.CurrentLevels,'Таблица спектра убывающих склеек',TabTechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                        OutputSaveXls(TabTempMonotoneRatio,app.CurrentLevels,'Таблица соотношений спектра убывающих склеек',TabTechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                end                
            end
            OutputSaveTxt(app.PartsExpAccelGlued{1}(:,2),'Экспонециально затухающий убывающий сигнал',TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            OutputSaveTxt(app.PartsExpAccelGlued{2}(:,2),'Экспонециально затухающий нулевой сигнал',TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            OutputSaveTxt(app.TableDecrementVisualize{1},['Декремент затухания по верхним частям фрагментов для частоты ' num2str(app.FreqDecrement) ' Гц'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            OutputSaveTxt(app.TableDecrementVisualize{3},['Декремент затухания по нижним частям фрагментов для частоты ' num2str(app.FreqDecrement) ' Гц'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
            %Сохранение ковариаций
            if ~isempty(app.CovarianceAccel) %Ковариация ускорений
                if size(app.CovarianceAccel,2) ~= 1 %Если нет сравнения с исходным сигналом
                    for i = 1:length(app.CurrentLevels) %Сохранение ковариаций всех уровней относительного базового
                        OutputSaveTxt(app.CovarianceAccel(:,i), ['Ковариация поверхности спектра ускорений №' num2str(app.CurrentLevels(i)) ' [#' num2str(app.CurrentLevels(app.ControlLevelNumb)) ']'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                    end
                else
                    OutputSaveTxt(app.CovarianceAccel, ['Ковариация спектров исходного и опорного #' num2str(app.CurrentLevels(app.ControlLevelNumb)) ' сигналов'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value);
                end
            end
            for s = 1:3 %[Increase, Neutral, Decrease]; 
                if ~isempty(app.CovarianceMonotone{s}) %Ковариация монотонных склеек
                    if size(app.CovarianceMonotone{s},2) ~= 1 %Если нет сравнения с исходным сигналом
                        for i = 1:length(app.CurrentLevels) 
                            switch s
                                case 1
                                    OutputSaveTxt(app.CovarianceMonotone{s}(:,i),['Ковариация поверхности спектра возрастающих склеек №' num2str(app.CurrentLevels(i))  ' [#' num2str(app.CurrentLevels(app.ControlLevelNumb)) ']'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value); %Base-Show
                                case 2 
                                    OutputSaveTxt(app.CovarianceMonotone{s}(:,i),['Ковариация поверхности спектра нейтральных склеек №' num2str(app.CurrentLevels(i))  ' [#' num2str(app.CurrentLevels(app.ControlLevelNumb)) ']'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value); %Base-Show
                                case 3
                                    OutputSaveTxt(app.CovarianceMonotone{s}(:,i),['Ковариация поверхности спектра убывающих склеек №' num2str(app.CurrentLevels(i))  ' [#' num2str(app.CurrentLevels(app.ControlLevelNumb)) ']'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value); %Base-Show
                            end   
                        end
                    else
                        switch s
                            case 1
                                OutputSaveTxt(app.CovarianceMonotone{s},['Ковариация спектров исходного и возрастающего опорного #' num2str(app.CurrentLevels(app.ControlLevelNumb)) ' сигналов'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value); %Base-Show
                            case 2
                                OutputSaveTxt(app.CovarianceMonotone{s},['Ковариация спектров исходного и нейтрального опорного #' num2str(app.CurrentLevels(app.ControlLevelNumb)) ' сигналов'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value); %Base-Show
                            case 3 
                                OutputSaveTxt(app.CovarianceMonotone{s},['Ковариация спектров исходного и убывающего опорного #' num2str(app.CurrentLevels(app.ControlLevelNumb)) ' сигналов'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value); %Base-Show
                        end
                    end
                end
            end
            if ~isempty(app.CovarianceExpAccel) %Ковариация экспонециально затухающих склеек
                [BaseCovLevel,ShowCovLevel] = CorrectNumbLevel(app,'Exp'); %Получение скорректированных индексов сигнала
                if size(app.CovarianceExpAccel,2) ~= 1 %Без сравнения с исходным сигналом
                    for i = 1:2
                        OutputSaveTxt(app.CovarianceExpAccel(:,i),['Ковариация поверхности спектра затухающих склеек №' num2str(i-2) ' [#' num2str(BaseCovLevel-2) ']'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value); %Base-Show
                    end
                else
                    OutputSaveTxt(app.CovarianceExpAccel,['Ковариация спектров исходного и затухающего опорного #' num2str(BaseCovLevel-2) ' сигналов'],TechnicalDataTemp,app.LastDir,app.EditAccelName.Value); %Base-Show
                end
            end
            %Параметры рассеяния
            if ~isempty(app.RegressionInformationAccel)
                OutputSaveInformation(app.RegressionInformationAccel, 'Параметры рассеяния склеек ускорений', app.LastDir, app.EditAccelName.Value);
            end
            for s = 1:3
                if ~isempty(app.RegressionInformationMonotone{s})
                    switch s
                        case 1
                            OutputSaveInformation(app.RegressionInformationMonotone{s}, 'Параметры рассеяния возрастающих склеек', app.LastDir, app.EditAccelName.Value);
                        case 2
                            OutputSaveInformation(app.RegressionInformationMonotone{s}, 'Параметры рассеяния нейтральных склеек', app.LastDir, app.EditAccelName.Value);
                        case 3
                            OutputSaveInformation(app.RegressionInformationMonotone{s}, 'Параметры рассеяния убывающих склеек', app.LastDir, app.EditAccelName.Value);
                    end
                end
            end
            if ~isempty(app.RegressionInformationExpAccel)
                OutputSaveInformation(app.RegressionInformationExpAccel, 'Параметры рассеяния экспонециально затухающих склеек', app.LastDir, app.EditAccelName.Value);
            end
            %Сохранение графика спектра исходных ускорений
            TempValue = app.ListBoxShowFigure.Value; %Сохранение выбора
            app.ListBoxShowFigure.Value = {'Спектр ускорений'};
            ListBoxShowFigureValueChanged(app, event); %Вызов окна отрисовки
            fig = gcf;
            addpath('Export_fig'); %Библиотека сохранения изображений
            export_fig(strcat(app.LastDir,'\Результаты\',app.EditAccelName.Value,'\Спектр ускорений'),'-dpng') %Save figure
            close(fig); %Закрываем окно по укзазателю
            app.ListBoxShowFigure.Value = TempValue; %Возвращаем значение для отображения
            
            %Сохранение настроек расчёта
            SettingsOutput{1,1} = 'FileNameDisplacement'; SettingsOutput{1,2} = app.FileNameDisplacement; %Название файла с перемещениями
            SettingsOutput{2,1} = 'FileNameAccel'; SettingsOutput{2,2} = app.FileNameAccel; %Название файла с ускорениями
            SettingsOutput{3,1} = 'StartReadNumbDisplacement'; SettingsOutput{3,2} = app.StartReadNumb; %Строка начала считывания данных для перемещений
            SettingsOutput{4,1} = 'StartReadNumbAccel'; SettingsOutput{4,2} = app.StartReadNumb; %Строка начала считывания данных для ускорений
            SettingsOutput{5,1} = 'LevelsNumb'; SettingsOutput{5,2} = app.LevelsNumb; %Число уровней для делений сигнала
            SettingsOutput{6,1} = 'LevelsStep'; SettingsOutput{6,2} = app.LevelsStep; %Высота уровней
            SettingsOutput{7,1} = 'Accuracy'; SettingsOutput{7,2} = app.Accuracy; %Точность аппроксимации
            SettingsOutput{8,1} = 'ShowNumb'; SettingsOutput{8,2} = app.CurrentLevels(app.ShowNumb); %Номер уровня для отображения
            SettingsOutput{9,1} = 'CutProcent'; SettingsOutput{9,2} = app.CutProcent; %Процентный порог отсечения от максимальной длины фрагмента
            SettingsOutput{10,1} = 'CorrectLengthMode'; SettingsOutput{10,2} = app.CorrectLengthMode; %Режим приведения склеек по длине
            SettingsOutput{11,1} = 'CorrectDisplacement'; SettingsOutput{11,2} = app.CorrectDisplacement; %Режим коррекции линейного дрейфа с перемешений
            SettingsOutput{12,1} = 'CutOffFrequency'; SettingsOutput{12,2} = app.CutOffFrequency; %Частота среза для двойного интегрирования
            SettingsOutput{13,1} = 'AccuracyExp'; SettingsOutput{13,2} = app.AccuracyExp; %Точность выделения экспонециального затухания
            SettingsOutput{14,1} = 'LimExpAccelLevel'; SettingsOutput{14,2} = app.LimExpAccelLevel; %Предельное значение диапазона ускорений
            SettingsOutput{15,1} = 'FreqDecrement'; SettingsOutput{15,2} = app.FreqDecrement ; %Частота выделения декремента затуханий
            SettingsOutput{16,1} = 'DepthGluing'; SettingsOutput{16,2} = app.DepthGluing; %Глубина прохода для склейки
            SettingsOutput{17,1} = 'ModelApprox'; SettingsOutput{17,2} = app.ModelApprox; %Модель выделения декремента затуханий
            SettingsOutput{18,1} = 'OverlapFactor'; SettingsOutput{18,2} = app.OverlapFactor; %Коэффициент перекрытия границ уровней
            SettingsOutput{19,1} = 'AccuracySpectrum'; SettingsOutput{19,2} = app.AccuracySpectrum; %Точность аппроксимации спектров
            SettingsOutput{20,1} = 'NormalizeMode'; SettingsOutput{20,2} = app.NormalizeMode; %Режим нормирование склеек
            
            SettingsSave(SettingsOutput,app.LastDir,app.EditAccelName.Value); %Сохранение настроек в файл
            app.ButtonSave.BackgroundColor = SaveColor; %Возвращение цвета клавише
        end

        % Button pushed function: ButtonTrash
        function ButtonTrashPushed(app, event)
            DeleteMode = 1; %Режим удаления выбранных элементов
            if ~isempty(app.ListBoxInput.Items)
                Temp = GetIndexSelFiles(app, DeleteMode);
            end
            ListBoxInputValueChanged(app, event);
        end

        % Value changed function: CheckBoxCorrectDisplacement, 
        % CheckBoxNormalizeMode, EditAccuracy, EditAccuracySpectrum, 
        % EditCutOffFrequency
        function CalculateParamsNew(app, event)
            %Обновление всех расчётных параметров
            if ~isempty(app.EditAccelName.Value) || ~isempty(app.EditDisplacementName.Value) %Если одной из полей заполнено
                if isempty(app.EditDisplacementName.Value)
                    UpdateCalcParam(app,'Accel',app.FileNameAccel);
                else
                    UpdateCalcParam(app,'Displacement',app.FileNameDisplacement);
                end
            end
        end

        % Value changed function: CheckBoxLogScale
        function CheckBoxLogScaleValueChanged(app, event)
            ListBoxShowFigureValueChanged(app, event); %Вызов события для отрисовки
        end

        % Value changed function: ListBoxInput
        function ListBoxInputValueChanged(app, event)
            MenuPoint = GetIndexSelMode(app); %Получение номера графика для построения
            if app.IndentCommonPlot
                IndexSelected = GetIndexSelFiles(app, 0); %Получить номера выбранных элементов
                if IndexSelected ~=0 %Если элементы выбраны
                    if ~isempty(app.PlotedName)
                        app.PlotedName = {}; %Обнуляем имена построенных графиков
                        clf(figure(1)); %Очищаем окно построения
                    end
                    CommonPlotFig1(app, IndexSelected);
                else
                    clf(figure(1)); close(figure(1));
                    app.PlotedName = {}; %Очищаем переменную с имена построенных графиков
                    app.EditAccelName.Value = ''; %Очистка поля ускорений
                    app.EditDisplacementName.Value = ''; %Очистка поля перемещений
                    app.FileNameDisplacement = ''; %Очистка переменной перемещений
                    app.FileNameAccel = ''; %Очистка переменной ускорений
                    CheckParameters(app); %Проверям заполнение полей исходных данных
                end
            end
            switch MenuPoint
                case 2
                    ComparePlot(app);
            end
        end

        % Value changed function: CheckBoxCompareInputSignal, 
        % CheckBoxSmoothingOption, ListBoxShowFigure
        function ListBoxShowFigureValueChanged(app, event)
            app.IndentCommonPlot = 0; %Начальное значение идентификатора построения в одних осях
            
            IndexSelected = GetIndexSelMode(app); %Получение номеров выбранных элементов
            LengthSelected = length(IndexSelected); %Число выбранных элементов
            
            close all; %Закрыть всё
            if ~strcmp(app.ButtonCalculate.Tag,'Ok') %Отображение по результатам расчёта за исключением №1
                for i = 1:LengthSelected
                    switch IndexSelected(i)
                        case 1
                            app.IndentCommonPlot = 1; %Идентификатор построение графиков в одних осях
                            ListBoxInputValueChanged(app, event); %Вызов на отрисовку
                        case 2
                            ComparePlot(app); %Построение сравнения сигналов
                    end
                end
                return;
            end
            %Построение в зависимости от выбора
            for i = 1:LengthSelected
                switch IndexSelected(i)
                    case 1
                        app.IndentCommonPlot = 1; %Идентификатор построение графиков в одних осях
                        ListBoxInputValueChanged(app, event); %Вызов на отрисовку
                    case 2
                        ComparePlot(app); %Построение сравнения сигналов
                    case 3
                        Fig3 = figure(3);
                        Fig3.Name = 'Временной сигнал ускорений';
                        hold on; grid on; %Сетка + построение графиков в одних осях
                        plot(app.PartsExpAccel{1}(:,1),app.PartsExpAccel{1}(:,2),'*','Color','blue'); %Убывающие фрагменты
                        plot(app.PartsExpAccel{2}(:,1),app.PartsExpAccel{2}(:,2),'*','Color','red'); %Нулевые фрагменты
                        plot([0 length(app.Accel)],[app.LimitsExpAccel(1) app.LimitsExpAccel(1)],'--','Color','black','LineWidth',1.5);
                        plot([0 length(app.Accel)],[app.LimitsExpAccel(2) app.LimitsExpAccel(2)],'--','Color','black','LineWidth',1.5);
                        for s = [1 3] %Верхние и нижние частичные сплайны
                            IndexFragments = find(app.PartsAccelApproxSpline{s}(:,3) == 1); %Индексы концов фрагментов
                            plot(app.PartsAccelApproxSpline{s}(1:IndexFragments(1),1),app.PartsAccelApproxSpline{s}(1:IndexFragments(1),2),'Color','green','LineWidth',1.5);
                            for i = 1:length(IndexFragments)-1
                                plot(app.PartsAccelApproxSpline{s}(IndexFragments(i)+1:IndexFragments(i + 1),1),app.PartsAccelApproxSpline{s}(IndexFragments(i)+1:IndexFragments(i + 1),2),'Color','green','LineWidth',1.5);
                            end
                        end
                        legend('Убывающие фрагменты','Нулевые фрагменты','Лимитирующая граница'); %Графическая легенда
                        title('Временной сигнал ускорений'); %Название графика
                        xlabel('t'); ylabel('r"'); %Названия осей
                    case 4
                        Fig4 = figure(4);
                        Fig4.Name = 'Спектр ускорений';
                        plot(app.FrequencyAccelInputFullFix, app.SpectrumAccelInputFullFix);
                        grid on; %Отображение масштабной сетки
                        title('Спектр ускорений'); %Название графика
                        xlabel('Частота'); ylabel('Амплитуда'); %Названия осей
                    case 5
                        Fig5 = figure(5);
                        Fig5.Name = 'Временной сигнал перемещений';
                        hold on; %Построение графиков в одних осях
                        plot(app.Time, app.Displacement); %Построение графика сигнала
                        plot(app.Time, app.DisplacementApprox,'Color','red'); %Аппроксимированные перемещениия
                        grid on; %Отображение масштабной сетки
                        title('Временной сигнал перемещений'); %Название графика
                        xlabel('t'); ylabel('r'); %Название осей
                        %Построение горизонтальных линий
                        Flag = 1; %Индикатор цветового деления
                        for i = 1:size(app.LineLevels, 1)
                            for j = [1 2] %Нижняя и верхняя линии
                                X = [0 length(app.Displacement)]; Y = app.LineLevels(i,j); %Построение горизонтальных линий
                                if Flag
                                    plot(X,[Y Y],'--','Color','black');
                                else
                                    plot(X,[Y Y],':','Color','red');
                                end
                            end
                            Flag = ~Flag;
                        end
                        %Подпись уровнеий
                        Flag = 1; %Индикатор цветового деления
                        for i = 1:size(app.LineLevels,1)
                            if Flag
                                text(X(2),mean(app.LineLevels(i,1:2)),num2str(app.LineLevels(i,3)), 'FontSize', 12, 'Color', 'black'); %Номера уровней
                            else
                                text(X(2),mean(app.LineLevels(i,1:2)),num2str(app.LineLevels(i,3)), 'FontSize', 12, 'Color', 'red'); %Номера уровней
                            end
                            Flag = ~Flag;
                        end
                    case 6
                        Fig6 = figure(6); %Новое графическое окно
                        Fig6.Name = 'Фрагментированный по заданному уровню график перемещений';
                        plot(app.PartsDisplacement{app.ShowNumb}(:,1),app.PartsDisplacement{app.ShowNumb}(:,2),'.'); %Построение графика
                        grid on; %Отображение масштабной сетки
                        title(['Фрагментированный по уровню №' num2str(app.SpinnerShowNumb.Value) ' график перемещений']); %Название графика
                        xlabel('t'); ylabel('r'); %Название осей
                    case 7
                        Fig7 = figure(7); %Новое графическое окно
                        Fig7.Name = 'Склееный по заданному уровню график ускорений';
                        plot((1:length(app.PartsAccelGlued{app.ShowNumb}))',app.PartsAccelGlued{app.ShowNumb}(:,2)); %Построение графика
                        hold on;
                        DotGluedShow = find(app.PartsAccelGlued{app.ShowNumb}(:,3) == 1); %Номера точек склейки для отображаемого канала
                        DotGluedShow(end) = []; %Исключение отображения конца уровня
                        plot(DotGluedShow,app.PartsAccelGlued{app.ShowNumb}(DotGluedShow,2),'*','Color','red'); %Отображение конца фрагмента
                        plot(DotGluedShow + 1,app.PartsAccelGlued{app.ShowNumb}(DotGluedShow + 1,2),'*','Color','black'); %Отображение начала следующего фрагмента
                        grid on; %Отображение масштабной сетки
                        title(['Склееный по уровню №' num2str(app.SpinnerShowNumb.Value) ' график ускорений']); %Название графика
                        xlabel('t'); ylabel('r"'); %Название осей
                    case 8
                        Fig8 = figure(8); %Новое графическое окно
                        Fig8.Name = 'Спектр заданного уровня';
                        plot(app.FrequencyAccelGluedFix, app.SpectrumAccelGluedVisualizeFix(:,app.ShowNumb)); %Построение графика
                        xlim([app.FrequencyAccelGluedFix(1) app.FrequencyAccelGluedFix(end)]); %Пределы по оси x
                        grid on; %Масштаб по осям
                        title(['Спектр уровня №' num2str(app.SpinnerShowNumb.Value)]); %Название графика
                        xlabel('Частота'); ylabel('Амплитуда'); %Название осей
                    case 9
                        Fig9 = figure(9); %Новое графическое окно
                        Fig9.Name = 'Поверхность спектра по всем уровням';
                        XData = app.CurrentLevels; YData = app.FrequencyAccelGluedFix; OutData = app.SpectrumAccelGluedVisualizeFix;
                        if app.CheckBoxLogScale.Value %Проверка типа масштаба осей
                            OutData = log(OutData);
                            if ~isreal(OutData)
                                OutData = abs(OutData);
                            end
                        end
                        if app.CheckBoxSmoothingOption.Value && length(app.CurrentLevels) ~= 1 %Сглаживание поверхности
                            XData(1) = XData(1) - 0.5; XData(end) = XData(end) + 0.5; %Сдвигаем горизонтальные границы поверхности
                            h = 0.01; %Шаг для интерполяции
                            [XDataMesh, YDataMesh] = meshgrid(XData(1):h:XData(end), YData(1):h:YData(end)); %Промежуточное разбиение (Smooth)
                            OutData = interp2(XData, YData, OutData, XDataMesh, YDataMesh,'Spline'); %Линейная интерполяция (Gradient)
                        end
                        imagesc('XData',XData,'YData',YData,'CData',OutData); %Построение плоского градиента
                        colormap jet; %Цветовая гамма поверхности
                        grid on; %Масштаб по осям
                        title('Поверхность спектра по всем уровням'); %Название графика
                        xlabel('Номер уровня'); ylabel('Частота'); zlabel('Амплитуда'); %Названия осей
                        ylim([app.FrequencyAccelGlued(1) app.FrequencyAccelGlued(end)]); %Пределы по оси Y
                        xlim([min(app.CurrentLevels)-0.5 max(app.CurrentLevels)+0.5]); %Пределы по оси X
                        colorbar; %Показать шкалу
                    case 10
                        Fig10 = figure(10); %Новое графическое окно
                        Fig10.Name = 'Склееный монотонный график сигнала';
                        for s = 1:3 %[Increase, Neutral, Decrease]
                            subplot(3,1,s); %Разделение графического окна
                            plot(app.PartsMonotoneAccelGlued{s}{app.ShowNumb}(:,2)); %Построение графика
                            DotGluedShow = find(app.PartsMonotoneAccelGlued{s}{app.ShowNumb}(:,3) == 1);
                            DotGluedShow(end) = []; %Исключение отображения конца уровня
                            hold on; grid on; %Построение графиков в одних осях
                            plot(DotGluedShow,app.PartsMonotoneAccelGlued{s}{app.ShowNumb}(DotGluedShow,2),'*','Color','red'); %Отображение конца фрагмента
                            plot(DotGluedShow + 1,app.PartsMonotoneAccelGlued{s}{app.ShowNumb}(DotGluedShow + 1,2),'*','Color','black'); %Отображение начала следующего фрагмента
                            switch s %Переключения заголовка для каждого канала
                                case 1
                                    title(['Склееный по уровню №' num2str(app.SpinnerShowNumb.Value) ' возрастающий сигнал ускорений']); %Название графика
                                case 2
                                    title(['Склееный по уровню №' num2str(app.SpinnerShowNumb.Value) ' нейтральный сигнал ускорений']); %Название графика
                                case 3
                                    title(['Склееный по уровню №' num2str(app.SpinnerShowNumb.Value) ' убывающий сигнал ускорений']); %Название графика
                            end
                            if s == 3, xlabel('t'); end
                            if s == 2, ylabel('r"'); end %Название осей
                        end
                    case 11
                        Fig11 = figure(11); %Новое графическое окно
                        Fig11.Name = 'Cпектр монотонного сигнала';
                        for s = 1:3 %[Increase, Neutral, Decrease]
                            subplot(3,1,s); %Разделение графического окна
                            plot(app.FrequencyMonotoneAccelGluedFix{s},app.SpectrumMonotoneAccelGluedVisualizeFix{s}(:,app.ShowNumb)); %Построение графика
                            xlim([app.FrequencyMonotoneAccelGluedFix{s}(1) app.FrequencyMonotoneAccelGluedFix{s}(end)]); %Пределы по оси x
                            grid on; %Масштаб по осям
                            switch s %Переключения заголовка для каждого канала
                                case 1
                                    title(['Cпектр возрастающих ускорений по уровню №' num2str(app.SpinnerShowNumb.Value)]); %Название графика
                                case 2
                                    title(['Cпектр нейтральных ускорений по уровню №' num2str(app.SpinnerShowNumb.Value)]); %Название графика
                                case 3
                                    title(['Cпектр убывающих ускорений по уровню №' num2str(app.SpinnerShowNumb.Value)]); %Название графика
                            end
                            if s == 3, xlabel('Частота'); end
                            if s == 2, ylabel('Амплитуда'); end %Название осей
                        end
                    case 12 %Поверхность спектра монотонных склеек
                        Fig12 = figure(12); %Новое графическое окно
                        Fig12.Name = 'Поверхность спектра монотонных склеек';
                        Fig12.Units = 'normalize'; Fig12.OuterPosition = [0 0 1 1]; %Во весь экран
                        for s = 1:3 %[Increase, Neutral, Decrease]
                            subplot(1,3,s); %Разделение графического окна
                            XData = app.CurrentLevels; YData = app.FrequencyMonotoneAccelGluedFix{s}; OutData = app.SpectrumMonotoneAccelGluedVisualizeFix{s};
                            if app.CheckBoxLogScale.Value %Проверка типа масштаба осей
                                OutData = log(OutData);
                                if ~isreal(OutData)
                                    OutData = abs(OutData);
                                end
                            end
                            if app.CheckBoxSmoothingOption.Value %Сглаживание поверхности
                                XData(1) = XData(1) - 0.5; XData(end) = XData(end) + 0.5; %Сдвигаем горизонтальные границы поверхности
                                h = 0.01; %Шаг для интерполяции
                                [XDataMesh, YDataMesh] = meshgrid(XData(1):h:XData(end), YData(1):h:YData(end)); %Промежуточное разбиение (Smooth)
                                OutData = interp2(XData, YData, OutData, XDataMesh, YDataMesh,'Spline'); %Линейная интерполяция (Gradient)
                            end
                            imagesc('XData',XData,'YData',YData,'CData',OutData); %Построение плоского градиента
                            colormap jet; %Цветовая гамма поверхности
                            grid on; %Масштаб по осям
                            switch s %Переключения заголовка для каждого канала
                                case 1
                                    title('Возрастающих'); %Название графика
                                case 2
                                    title('Нейтральных'); %Название графика
                                case 3
                                    title('Убывающих'); %Название графика
                            end
                            if s == 2, xlabel('Номер уровня'); end
                            if s == 1, ylabel('Частота'); end
                            ylim([app.FrequencyMonotoneAccelGlued{s}(1) app.FrequencyMonotoneAccelGlued{s}(end)]); %Пределы по оси Y
                            xlim([min(app.CurrentLevels)-0.5 max(app.CurrentLevels)+0.5]); %Пределы по оси X
                            colorbar; %Показать шкалу
                        end
                    case 13 %Склейка затухающих фрагментов
                        Fig13 = figure(13); %Новое графическое окно
                        Fig13.Name = 'Склейка экспонециально затухающих фрагментов ускорений';
                        [~,ShowLevel] = CorrectNumbLevel(app,'Exp'); %Получение скорректированных индексов сигнала
                        plot(app.PartsExpAccelGlued{ShowLevel}(:,2)); %Убывающие склееные
                        DotGluedShow = find(app.PartsExpAccelGlued{ShowLevel}(:,3) == 1);
                        DotGluedShow(end) = []; %Исключение отображения конца уровня
                        hold on; grid on; %Построение графиков в одних осях
                        plot(DotGluedShow,app.PartsExpAccelGlued{ShowLevel}(DotGluedShow,2),'*','Color','red'); %Отображение конца фрагмента
                        plot(DotGluedShow + 1,app.PartsExpAccelGlued{ShowLevel}(DotGluedShow + 1,2),'*','Color','black'); %Отображение начала следующего фрагмента
                        title(['Склееный по уровню №' num2str(ShowLevel - 2) ' экспонециально затухающий сигнал ускорений']); %Название графика
                        xlabel('t'); ylabel('r"'); %Название осей
                    case 14 %Спектр затухающих склеек
                        Fig14 = figure(14); %Новое графическое окно
                        Fig14.Name = 'Спектр затухающих склеек ускорений';
                        [~,ShowLevel] = CorrectNumbLevel(app,'Exp'); %Получение скорректированных индексов сигнала
                        plot(app.FrequencyExpAccelGluedFix,app.SpectrumExpAccelGluedVisualizeFix(:,ShowLevel)); %Построение графика
                        xlim([app.FrequencyExpAccelGluedFix(1) app.FrequencyExpAccelGluedFix(end)]); %Пределы по оси x
                        grid on; %Масштаб по осям
                        title(['Спектр затухающих склеек ускорений по уровню №' num2str(ShowLevel - 2)]); %Название графика
                        xlabel('Частота'); ylabel('Амплитуда'); %Название осей
                    case 15 %Поверхность спектра затухающих ускорений
                        Fig15 = figure(15); %Новое графическое окно
                        Fig15.Name = 'Поверхность спектра затухающих ускорений';
                        XData = [-1 0]; YData = app.FrequencyExpAccelGluedFix; OutData = app.SpectrumExpAccelGluedVisualizeFix;
                        if app.CheckBoxLogScale.Value %Проверка типа масштаба осей
                            OutData = log(OutData);
                            if ~isreal(OutData)
                                OutData = abs(OutData);
                            end
                        end
                        if app.CheckBoxSmoothingOption.Value %Сглаживание поверхности
                            XData(1) = XData(1) - 0.5; XData(end) = XData(end) + 0.5; %Сдвигаем горизонтальные границы поверхности
                            h = 0.01; %Шаг для интерполяции
                            [XDataMesh, YDataMesh] = meshgrid(XData(1):h:XData(end), YData(1):h:YData(end)); %Промежуточное разбиение (Smooth)
                            OutData = interp2(XData, YData, OutData, XDataMesh, YDataMesh,'Spline'); %Линейная интерполяция (Gradient)
                        end
                        imagesc('XData',XData,'YData',YData,'CData',OutData); %Построение плоского градиента
                        colormap jet; %Цветовая гамма поверхности
                        grid on; %Масштаб по осям
                        title('Поверхность спектра экспонециально затухающих склеек ускорений'); %Название графика
                        xticks([-1 0]); %Разметка номеров на осях
                        xlabel('Номер уровня'); ylabel('Частота'); zlabel('Амплитуда'); %Названия осей
                        ylim([app.FrequencyExpAccelGlued(1) app.FrequencyExpAccelGlued(end)]); %Пределы по оси Y
                        xlim([-1.5 0.5]); %Пределы по оси X
                        colorbar; %Показать шкалу
                    case 16 %Поверхность декрементов верхних частей
                        Fig16 = figure(16); %Новое графическое окно
                        Fig16.Name = 'Поверхность декрементов верхних частей ускорений';
                        XData = 1:size(app.TableDecrementVisualize{1},2); %Номера фрагментов
                        YData = 1:size(app.TableDecrementVisualize{1},1); %Номера периодов
                        imagesc('XData',XData,'YData',YData ,'CData',app.TableDecrementVisualize{1}); %Построение плоского градиента
                        colormap jet; %Цветовая гамма поверхности
                        grid on; %Масштаб по осям
                        title('Поверхность декрементов верхних частей ускорений'); %Название графика
                        xlabel('Номер периода'); ylabel('Номер фрагмента'); zlabel('Величина декремента'); %Названия осей
                        ylim([YData(1)-0.5 YData(end)+0.5]); %Пределы по оси Y
                        xlim([XData(1)-0.5 XData(end)+0.5]); %Пределы по оси X
                        yticks(YData); %Разметка номеров на осях
                        colorbar; %Показать шкалу
                    case 17 %Поверхность декрементов нижних частей
                        Fig17 = figure(17); %Новое графическое окно
                        Fig17.Name = 'Поверхность декрементов нижних частей ускорений';
                        XData = 1:size(app.TableDecrementVisualize{3},2); %Номера фрагментов
                        YData = 1:size(app.TableDecrementVisualize{3},1); %Номера периодов
                        imagesc('XData',XData,'YData',YData ,'CData',app.TableDecrementVisualize{3}); %Построение плоского градиента
                        colormap jet; %Цветовая гамма поверхности
                        grid on; %Масштаб по осям
                        title('Поверхность декрементов нижних частей ускорений'); %Название графика
                        xlabel('Номер периода'); ylabel('Номер фрагмента'); zlabel('Величина декремента'); %Названия осей
                        ylim([YData(1)-0.5 YData(end)+0.5]); %Пределы по оси Y
                        xlim([XData(1)-0.5 XData(end)+0.5]); %Пределы по оси X
                        yticks(YData); %Разметка номеров на осях
                        colorbar; %Показать шкалу
                    case 18 %Разница поверхности спектра ускорений
                        Fig18 = figure(18); %Новое графическое окно
                        Fig18.Name = 'Разница поверхности спектра по всем уровням';
                        XData = app.CurrentLevels; YData = app.FrequencyAccelGluedFix; OutData = app.SpectrumAccelGluedVisualizeControlFix;
                        if app.CheckBoxSmoothingOption.Value && length(app.CurrentLevels) ~= 1
                            XData(1) = XData(1) - 0.5; XData(end) = XData(end) + 0.5; %Сдвигаем горизонтальные границы поверхности
                            h = 0.01; %Шаг для интерполяции
                            [XDataMesh, YDataMesh] = meshgrid(XData(1):h:XData(end), YData(1):h:YData(end)); %Промежуточное разбиение (Smooth)
                            OutData = interp2(XData, YData, OutData, XDataMesh, YDataMesh,'Spline'); %Линейная интерполяция (Gradient)
                        end
                        imagesc('XData',XData,'YData',YData,'CData',OutData); %Построение плоского градиента
                        colormap jet; %Цветовая гамма поверхности
                        grid on; %Масштаб по осям
                        title('Разница поверхности спектра ускорений'); %Название графика
                        xlabel('Номер уровня'); ylabel('Частота'); zlabel('Амплитуда'); %Названия осей
                        ylim([app.FrequencyAccelGlued(1) app.FrequencyAccelGlued(end)]); %Пределы по оси Y
                        xlim([min(app.CurrentLevels)-0.5 max(app.CurrentLevels)+0.5]); %Пределы по оси X
                        colorbar; %Показать шкалу
                    case 19 %Разница поверхности спектра монотонных склеек
                        Fig19 = figure(19); %Новое графическое окно
                        Fig19.Name = 'Разница поверхности спектра монотонных склеек';
                        Fig19.Units = 'normalize'; Fig19.OuterPosition = [0 0 1 1]; %Во весь экран
                        for s = 1:3
                            subplot(1,3,s); %Разделение графического окна
                            XData = app.CurrentLevels; YData = app.FrequencyMonotoneAccelGluedFix{s}; OutData = app.SpectrumMonotoneAccelGluedVisualizeControlFix{s};
                            if app.CheckBoxSmoothingOption.Value
                                XData(1) = XData(1) - 0.5; XData(end) = XData(end) + 0.5; %Сдвигаем горизонтальные границы поверхности
                                h = 0.01; %Шаг для интерполяции
                                [XDataMesh, YDataMesh] = meshgrid(XData(1):h:XData(end), YData(1):h:YData(end)); %Промежуточное разбиение (Smooth)
                                OutData = interp2(XData, YData, OutData, XDataMesh, YDataMesh,'Spline'); %Линейная интерполяция (Gradient)
                            end
                            imagesc('XData',XData,'YData',YData,'CData',OutData); %Построение плоского градиента
                            colormap jet; %Цветовая гамма поверхности
                            grid on; %Масштаб по осям
                            switch s %Переключения заголовка для каждого канала
                                case 1
                                    title('Возрастающих'); %Название графика
                                case 2
                                    title('Нейтральных'); %Название графика
                                case 3
                                    title('Убывающих'); %Название графика
                            end
                            if s == 2, xlabel('Номер уровня'); end
                            if s == 1, ylabel('Частота'); end
                            ylim([app.FrequencyMonotoneAccelGlued{s}(1) app.FrequencyMonotoneAccelGlued{s}(end)]); %Пределы по оси Y
                            xlim([min(app.CurrentLevels)-0.5 max(app.CurrentLevels)+0.5]); %Пределы по оси X
                            colorbar; %Показать шкалу
                        end
                    case 20 %Разница поверхности спектра затухающих ускорений
                        Fig20 = figure(20); %Новое графическое окно
                        Fig20.Name = 'Разница поверхности спектра затухающих ускорений';
                        XData = [-1 0]; YData = app.FrequencyExpAccelGluedFix; OutData = app.SpectrumExpAccelGluedVisualizeControlFix;
                        if app.CheckBoxSmoothingOption.Value
                            XData(1) = XData(1) - 0.5; XData(end) = XData(end) + 0.5; %Сдвигаем горизонтальные границы поверхности
                            h = 0.01; %Шаг для интерполяции
                            [XDataMesh, YDataMesh] = meshgrid(XData(1):h:XData(end), YData(1):h:YData(end)); %Промежуточное разбиение (Smooth)
                            OutData = interp2(XData, YData, OutData, XDataMesh, YDataMesh,'Spline'); %Линейная интерполяция (Gradient)
                        end
                        imagesc('XData',XData,'YData',YData,'CData',OutData); %Построение плоского градиента
                        colormap jet; %Цветовая гамма поверхности
                        grid on; %Масштаб по осям
                        title('Разница поверхности спектра экспонециально затухающих склеек ускорений'); %Название графика
                        xticks([-1 0]); %Разметка номеров на осях
                        xlabel('Номер уровня'); ylabel('Частота'); zlabel('Амплитуда'); %Названия осей
                        ylim([app.FrequencyExpAccelGlued(1) app.FrequencyExpAccelGlued(end)]); %Пределы по оси Y
                        xlim([-1.5 0.5]); %Пределы по оси X
                        colorbar; %Показать шкалу
                    case 21 %Ковариация поверхности спектра ускорений
                        Fig21 = figure(21); %Новое графическое окно
                        Fig21.Name = 'Ковариация поверхности спектра ускорений';
                        if app.CheckBoxCompareInputSignal.Value %Проверка режима сравнения с спектром исходного сигнала
                            %Ковариация спектров ускорений исходного и опорного сигналов
                            app.CovarianceAccel = xcov(app.SpectrumAccelInputGluedFix,app.SpectrumAccelGluedVisualizeFix(:,app.ControlLevelNumb),'coeff'); %Кросс-ковариация
                        else
                            app.CovarianceAccel = []; %Обнуление результатов предыдущего расчёта
                            for i = 1:length(app.CurrentLevels)
                                app.CovarianceAccel(:,i) = xcov(app.SpectrumAccelGluedVisualizeFix(:,app.ControlLevelNumb),app.SpectrumAccelGluedVisualizeFix(:,i),'coeff');
                            end
                        end
                        if size(app.CovarianceAccel,2) ~= 1 %Без сравнения с исходным сигналом
                            stem(app.FrequencyAccelGluedFix, app.CovarianceAccel(1:length(app.FrequencyAccelGluedFix),app.ShowNumb)); %Построение распределения
                        else
                            stem(app.FrequencyAccelGluedFix, app.CovarianceAccel(1:length(app.FrequencyAccelGluedFix))); %Построение распределения
                        end
                        if app.CheckBoxCompareInputSignal.Value %Проверка режима сравнения с спектром исходного сигнала
                            title(['Ковариация спектров исходного и опорного #' num2str(app.SpinnerControlLevelNumb.Value) ' сигналов']);
                        else
                            title(['Ковариация поверхности спектра ускорений №' num2str(app.SpinnerShowNumb.Value) ' [#' num2str(app.CurrentLevels(app.ControlLevelNumb)) ']']);
                        end
                        grid on; %Отображение масштабной сетки
                        xlabel('Частота'); ylabel('Нормализованный коэффициент ковариации'); %Названия осей
                    case 22 %Ковариация поверхности спектра монотонных склеек
                        Fig22 = figure(22); %Новое графическое окно
                        Fig22.Name = 'Ковариация поверхности спектра монотонных склеек';
                        for s = 1:3
                            Plt(s) = subplot(3,1,s); %Деление графического окна
                            if app.CheckBoxCompareInputSignal.Value %Проверка режима сравнения с спектром исходного сигнала
                                app.CovarianceMonotone{s} = xcov(app.SpectrumAccelInputMonotoneFix{s},app.SpectrumMonotoneAccelGluedVisualizeFix{s}(:,app.ControlLevelNumb),'coeff');
                            else
                                app.CovarianceMonotone = {}; %Обнуление результатов предыдущего расчёта
                                for i = 1:length(app.CurrentLevels)
                                    app.CovarianceMonotone{s}(:,i) = xcov(app.SpectrumMonotoneAccelGluedVisualizeFix{s}(:,app.ControlLevelNumb),app.SpectrumMonotoneAccelGluedVisualizeFix{s}(:,i),'coeff');
                                end
                            end
                            if size(app.CovarianceMonotone{s},2) ~= 1 %Без сравнения с исходным сигналом
                                stem(app.FrequencyMonotoneAccelGluedFix{s},app.CovarianceMonotone{s}(1:length(app.FrequencyMonotoneAccelGluedFix{s}),app.ShowNumb)); %Построение распределения
                            else
                                stem(app.FrequencyMonotoneAccelGluedFix{s},app.CovarianceMonotone{s}(1:length(app.FrequencyMonotoneAccelGluedFix{s}))); %Построение распределения
                            end
                            if app.CheckBoxCompareInputSignal.Value %Проверка режима сравнения с спектром исходного сигнала
                                switch s
                                    case 1
                                        title(['Ковариация спектров исходного и возрастающего опорного #' num2str(app.SpinnerControlLevelNumb.Value) ' сигналов']);
                                    case 2
                                        title(['Ковариация спектров исходного и нейтрального опорного #' num2str(app.SpinnerControlLevelNumb.Value) ' сигналов']);
                                    case 3
                                        title(['Ковариация спектров исходного и убывающего опорного #' num2str(app.SpinnerControlLevelNumb.Value) ' сигналов']);
                                end
                            else
                                switch s
                                    case 1
                                        title(['Ковариация поверхности спектра возрастающих склеек №' num2str(app.SpinnerShowNumb.Value) ' [#' num2str(app.CurrentLevels(app.ControlLevelNumb)) ']']);
                                    case 2
                                        title(['Ковариация поверхности спектра нейтральных склеек №' num2str(app.SpinnerShowNumb.Value) ' [#' num2str(app.CurrentLevels(app.ControlLevelNumb)) ']']);
                                    case 3
                                        title(['Ковариация поверхности спектра убывающих склеек №' num2str(app.SpinnerShowNumb.Value) ' [#' num2str(app.CurrentLevels(app.ControlLevelNumb)) ']']);
                                end
                            end
                            grid on; %Отображение масштабной сетки
                            if s == 3, xlabel('Частота'); end
                        end
                        HeightSubplot = Plt(1).Position(2) - Plt(3).Position(2) + Plt(3).Position(4);
                        HiAx = axes('position', [Plt(3).Position(1) Plt(3).Position(2) Plt(3).Position(3) HeightSubplot],'visible','off');
                        ylabel(HiAx,'Нормализованный коэффициент ковариации','visible','on');
                    case 23 %Ковариация поверхности спектра затухающих склеек
                        Fig23 = figure(23); %Новое графическое окно
                        Fig23.Name = 'Ковариация поверхности спектра затухающих склеек';
                        [BaseCovLevel,ShowCovLevel] = CorrectNumbLevel(app,'Exp'); %Получение скорректированных индексов сигнала
                        if app.CheckBoxCompareInputSignal.Value %Проверка режима сравнения с спектром исходного сигнала
                            app.CovarianceExpAccel = xcov(app.SpectrumAccelInputExpFix,app.SpectrumExpAccelGluedVisualizeFix(:,BaseCovLevel),'coeff');
                        else
                            app.CovarianceExpAccel = []; %Обнуление результатов предыдущего расчёта
                            for i = 1:2 %[-1 0]
                                app.CovarianceExpAccel(:,i) = xcov(app.SpectrumExpAccelGluedVisualizeFix(:,BaseCovLevel),app.SpectrumExpAccelGluedVisualizeFix(:,i),'coeff');
                            end
                        end
                        if size(app.CovarianceExpAccel,2) ~= 1 %Без сравнения с исходным сигналом
                            stem(app.FrequencyExpAccelGluedFix,app.CovarianceExpAccel(1:length(app.FrequencyExpAccelGluedFix),ShowCovLevel)); %Построение распределения
                        else
                            stem(app.FrequencyExpAccelGluedFix,app.CovarianceExpAccel(1:length(app.FrequencyExpAccelGluedFix))); %Построение распределения
                        end
                        if app.CheckBoxCompareInputSignal.Value %Проверка режима сравнения с спектром исходного сигнала
                            title(['Ковариация спектров исходного и затухаюшего опорного #' num2str(BaseCovLevel-2) ' сигналов']);
                        else
                            title(['Ковариация поверхности спектра затухающих склеек №' num2str(ShowCovLevel-2) ' [#' num2str(BaseCovLevel-2) ']']);
                        end
                        grid on; %Отображение масштабной сетки
                        xlabel('Частота'); ylabel('Нормализованный коэффициент ковариации'); %Названия осей
                    case 24 %Рассеяние поверхности спектра ускорений
                        Fig24 = figure(24); %Новое графическое окно
                        Fig24.Name = 'Рассяения поверхности спектра ускорений';
                        hold on; %Построение графиков в одних осях
                        BaseSignal = app.SpectrumAccelGluedVisualizeFix(:,app.ControlLevelNumb); %Базовый сигнал
                        if app.CheckBoxCompareInputSignal.Value %Проверка режима сравнения с спектром исходного сигнала
                            ShowSignal = app.SpectrumAccelInputGluedFix;
                        else
                            for i = 1:length(app.CurrentLevels) %Зависимость всех уровней от базового
                                ShowSignal(:,i) = app.SpectrumAccelGluedVisualizeFix(:,i); %Сигнал для отображения
                            end
                        end
                        if ~app.CheckBoxLogScale.Value %Проверка типа масштаба осей
                            title('Рассяения поверхности спектра ускорений');
                        else
                            ShowSignal = log(ShowSignal); BaseSignal = log(BaseSignal);
                            if ~isreal(BaseSignal), BaseSignal = abs(BaseSignal); end
                            if ~isreal(ShowSignal), ShowSignal = abs(ShowSignal); end
                            title('Логарифмическое рассяения поверхности спектра ускорений');
                        end
                        %Получаем распределение цветов фиксированной длины
                        Colors = jet(length(BaseSignal));
                        Colors = Colors(end:-1:1,:); %Переворот матрицы цветов
                        colormap(Colors);
                        cb = colorbar; %Цветовая шкала
                        cb.Label.String = 'Частоты';
                        %Формирование Colorbar
                        LowFreq = app.SpinnerFixSpectrumLow.Value; HighFreq = app.SpinnerFixSpectrumHigh.Value; %Получение граничных частот
                        for i = 1:length(cb.Ticks)
                            cb.TickLabels{i} = LowFreq + (i - 1)*(HighFreq - LowFreq)/(length(cb.Ticks) - 1); %Метки границ
                        end
                        %Построение линейной регрессии
                        for i = 1:size(ShowSignal,2)
                            LinearRegressionCoeffs(:,i) = polyfit(BaseSignal,ShowSignal(:,i),1); %Коэффициенты для линейной регрессии
                            LinearRegressionFun(:,i) = polyval(LinearRegressionCoeffs(:,i),BaseSignal); %Вычисление значений линейной регрессии
                            DistanceScatter(i) = sum(abs(ShowSignal(:,i) - LinearRegressionFun(:,i))); %Дистанция рассеяния
                            [MaxBaseSignal(i) MaxBaseSignalInd] = max(BaseSignal); [MaxShowSignal(i) MaxShowSignalInd] = max(ShowSignal(:,i)); %Максимумы рассеяния
                            FreqMaxBaseSignal(i) = app.FrequencyAccelGluedFix(MaxBaseSignalInd); FreqMaxShowSignal(i) = app.FrequencyAccelGluedFix(MaxShowSignalInd);
                            LengthCurve(i) = 0; %Инициализация длины кривой
                            for j = 1:length(BaseSignal) - 1
                                if DistanceScatter(i) > 1e-15 %Обработка авторегресии
                                    LengthCurve(i) = LengthCurve(i) + sqrt((BaseSignal(j+1) - BaseSignal(j))^2 + (ShowSignal(j+1,i) - ShowSignal(j,i))^2); %Длина кривой
                                elseif (BaseSignal(j+1) >= BaseSignal(j))
                                    LengthCurve(i) = LengthCurve(i) + sqrt((BaseSignal(j+1) - BaseSignal(j))^2 + (ShowSignal(j+1,i) - ShowSignal(j,i))^2); %Длина кривой
                                end
                            end
                            app.RegressionInformationAccel.HeadersCols{i} = num2str(app.CurrentLevels(i)); %Заголовки информации
                        end
                        if size(ShowSignal,2) ~= 1 %Проверка сравнения с исходным сигналом
                            scatter(BaseSignal,ShowSignal(:,app.ShowNumb),[],Colors,'*'); %Построение рассеяния
                            plot(BaseSignal,LinearRegressionFun(:,app.ShowNumb)); %Линейная регрессия
                            %Отображение легенды
                            if LinearRegressionCoeffs(2,app.ShowNumb) > 0
                                Factor = '+ ';
                            else
                                Factor = '- ';
                            end
                            plot(BaseSignal(1),ShowSignal(1,app.ShowNumb),'.','Color',Colors(1,:)); %Точка для отображение меры рассеяния в легенде
                            plot(BaseSignal(1),ShowSignal(1,app.ShowNumb),'x','Color',Colors(end,:)); %Точка для отображение максимума в легенде
                            plot(BaseSignal(1),ShowSignal(1,app.ShowNumb),'+','Color',Colors(1,:)); %Точка для отображение длины кривой в легенде
                            GraphLabel = ['y = ' num2str(LinearRegressionCoeffs(1,app.ShowNumb)) 'x ' Factor num2str(abs(LinearRegressionCoeffs(2,app.ShowNumb)))];
                            Leg = legend('Данные рассеяния', GraphLabel, ['Дистанция рассеяния = ' num2str(DistanceScatter(app.ShowNumb))],['Cоотношение максимумов: ' num2str(MaxBaseSignal(app.ShowNumb),'%.2e') '/' num2str(MaxShowSignal(app.ShowNumb),'%.2e') ' (' num2str(FreqMaxBaseSignal(app.ShowNumb),'%.2f') '/' num2str(FreqMaxShowSignal(app.ShowNumb),'%.2f') ' Гц)'],['Длина кривой рассеяния: ' num2str(LengthCurve(app.ShowNumb), '%.2e')]);
                        else
                            scatter(BaseSignal,ShowSignal,[],Colors,'*'); %Построение рассеяния
                            plot(BaseSignal,LinearRegressionFun); %Линейная регрессия
                            %Отображение легенды
                            if LinearRegressionCoeffs(2) > 0
                                Factor = '+ ';
                            else
                                Factor = '- ';
                            end
                            plot(BaseSignal(1),ShowSignal(1),'.','Color',Colors(1,:)); %Точка для отображение меры рассеяния в легенде
                            plot(BaseSignal(1),ShowSignal(1),'x','Color',Colors(end,:)); %Точка для отображение максимума в легенде
                            plot(BaseSignal(1),ShowSignal(1),'+','Color',Colors(1,:)); %Точка для отображение длины кривой в легенде
                            GraphLabel = ['y = ' num2str(LinearRegressionCoeffs(1)) 'x ' Factor num2str(abs(LinearRegressionCoeffs(2)))];
                            Leg = legend('Данные рассеяния', GraphLabel,['Дистанция рассеяния = ' num2str(DistanceScatter)],['Cоотношение максимумов: ' num2str(MaxBaseSignal,'%.2e') '/' num2str(MaxShowSignal,'%.2e') ' (' num2str(FreqMaxBaseSignal,'%.2f') '/' num2str(FreqMaxShowSignal,'%.2f') ' Гц)'], ['Длина кривой рассеяния: ' num2str(LengthCurve, '%.2e')]);
                        end
                        legend('boxoff'); %Легенда без рамки
                        Leg.Location = 'best';
                        grid on; %Отображение масштабной сетки
                        xlabel(['Cигнал базового уровня #' num2str(app.CurrentLevels(app.ControlLevelNumb))]);
                        if app.CheckBoxCompareInputSignal.Value %Проверка режима сравнения с спектром исходного сигнала
                            ylabel('Исходный сигнал ускорений');
                        else
                            ylabel(['Сигнал уровня №' num2str(app.CurrentLevels(app.ShowNumb))]); %Названия осей
                        end
                        %Запись информации о расчёте
                        app.RegressionInformationAccel.Values = [LinearRegressionCoeffs; DistanceScatter; MaxBaseSignal; MaxShowSignal; FreqMaxBaseSignal; FreqMaxShowSignal; LengthCurve];
                        app.RegressionInformationAccel.HeadersRows = {'a ','b ','Distance ', 'Max base signal ', 'Max show signal ', 'Freq base signal ', 'Freq show signal ', 'Length curve '};
                        clear BaseSignal ShowSignal LengthCurve DistanceScatter LinearRegressionCoeffs...
                            LinearRegressionFun MaxBaseSignal MaxShowSignal FreqMaxBaseSignal FreqMaxShowSignal; %Очистка переменных
                    case 25 %Рассеяние поверхности спектра монотонных склеек
                        for s = 1:3
                            Fig = figure(250+s); %Новое графическое окно
                            Fig.Units = 'normalize'; %Безразмерная система единиц
                            switch s 
                                case 1
                                    Fig.Name = 'Рассеяние поверхности спектра возрастающих склеек';
                                    Fig.OuterPosition(1) = 0; %Левый угол экрана
                                    Fig.OuterPosition(2) = 1 - Fig.OuterPosition(4);
                                case 2
                                    Fig.Name = 'Рассеяние поверхности спектра нейтральных склеек';
                                    Fig.OuterPosition(1) = 0.5 - Fig.OuterPosition(3)/2; %Центр экрана
                                    Fig.OuterPosition(2) = 1 - Fig.OuterPosition(4); 
                                case 3
                                    Fig.Name = 'Рассеяние поверхности спектра убывающих склеек';
                                    Fig.OuterPosition(1) = 1 - Fig.OuterPosition(3); %Правый угол экрана
                                    Fig.OuterPosition(2) = 1 - Fig.OuterPosition(4);
                            end
                            hold on; %Построение графиков в одних осях
                            BaseSignal = app.SpectrumMonotoneAccelGluedVisualizeFix{s}(:,app.ControlLevelNumb);
                            if app.CheckBoxCompareInputSignal.Value %Проверка режима сравнения с спектром исходного сигнала
                                ShowSignal = app.SpectrumAccelInputMonotoneFix{s};
                            else
                                for i = 1:length(app.CurrentLevels) %Зависимость всех уровней от базового
                                    ShowSignal(:,i) = app.SpectrumMonotoneAccelGluedVisualizeFix{s}(:,i); %Сигнал для отображения
                                end
                            end
                            if ~app.CheckBoxLogScale.Value %Проверка типа масштаба осей
                                switch s
                                    case 1
                                        title('Рассеяние поверхности спектра возрастающих склеек');
                                    case 2
                                        title('Рассеяние поверхности спектра нейтральных склеек');
                                    case 3
                                        title('Рассеяние поверхности спектра убывающих склеек');
                                end         
                            else
                                BaseSignal = log(BaseSignal); ShowSignal = log(ShowSignal);
                                if ~isreal(BaseSignal), BaseSignal = abs(BaseSignal); end
                                if ~isreal(ShowSignal), ShowSignal = abs(ShowSignal); end
                                switch s
                                    case 1
                                        title('Логарифмическое рассеяние поверхности спектра возрастающих склеек');
                                    case 2
                                        title('Логарифмическое рассеяние поверхности спектра нейтральных склеек');
                                    case 3
                                        title('Логарифмическое рассеяние поверхности спектра убывающих склеек');
                                end
                            end
                            %Получаем распределение цветов фиксированной длины
                            Colors = jet(length(BaseSignal));
                            Colors = Colors(end:-1:1,:); %Переворот матрицы цветов
                            colormap(Colors);
                            cb = colorbar; %Цветовая шкала
                            cb.Label.String = 'Частоты';
                            %Формирование Colorbar
                            LowFreq = app.SpinnerFixSpectrumLow.Value; HighFreq = app.SpinnerFixSpectrumHigh.Value; %Получение граничных частот
                            for i = 1:length(cb.Ticks)
                                cb.TickLabels{i} = LowFreq + (i - 1)*(HighFreq - LowFreq)/(length(cb.Ticks) - 1); %Метки границ
                            end
                            %Построение линейной регрессии
                            for i = 1:size(ShowSignal,2)
                                LinearRegressionCoeffs(:,i) = polyfit(BaseSignal,ShowSignal(:,i),1); %Коэффициенты для линейной регрессии
                                LinearRegressionFun(:,i) = polyval(LinearRegressionCoeffs(:,i),BaseSignal); %Вычисление значений линейной регрессии
                                DistanceScatter(i) = sum(abs(ShowSignal(:,i) - LinearRegressionFun(:,i))); %Дистанция рассеяния
                                [MaxBaseSignal(i) MaxBaseSignalInd] = max(BaseSignal); [MaxShowSignal(i) MaxShowSignalInd] = max(ShowSignal(:,i)); %Максимумы рассеяния
                                FreqMaxBaseSignal(i) = app.FrequencyMonotoneAccelGluedFix{s}(MaxBaseSignalInd); FreqMaxShowSignal(i) = app.FrequencyMonotoneAccelGluedFix{s}(MaxShowSignalInd); %Частоты соотношения максимумов
                                LengthCurve(i) = 0; %Инициализация длины кривой
                                for j = 1:length(BaseSignal) - 1
                                    if DistanceScatter(i) > 1e-15 %Обработка авторегресии
                                        LengthCurve(i) = LengthCurve(i) + sqrt((BaseSignal(j+1) - BaseSignal(j))^2 + (ShowSignal(j+1,i) - ShowSignal(j,i))^2); %Длина кривой
                                    elseif (BaseSignal(j+1) >= BaseSignal(j))
                                        LengthCurve(i) = LengthCurve(i) + sqrt((BaseSignal(j+1) - BaseSignal(j))^2 + (ShowSignal(j+1,i) - ShowSignal(j,i))^2); %Длина кривой
                                    end
                                end
                                app.RegressionInformationMonotone{s}.HeadersCols{i} = num2str(app.CurrentLevels(i)); %Заголовки информации
                            end
                            if size(ShowSignal,2) ~= 1 %Проверка сравнения с исходным сигналом
                                scatter(BaseSignal,ShowSignal(:,app.ShowNumb),[],Colors,'*'); %Построение рассеяния
                                plot(BaseSignal,LinearRegressionFun(:,app.ShowNumb)); %Линейная регрессия
                                %Отображение легенды
                                if LinearRegressionCoeffs(2,app.ShowNumb) > 0
                                    Factor = '+ ';
                                else
                                    Factor = '- ';
                                end
                                plot(BaseSignal(1),ShowSignal(1,app.ShowNumb),'.','Color',Colors(1,:)); %Точка для отображение меры рассеяния в легенде
                                plot(BaseSignal(1),ShowSignal(1,app.ShowNumb),'x','Color',Colors(end,:)); %Точка для отображение максимума в легенде
                                plot(BaseSignal(1),ShowSignal(1,app.ShowNumb),'+','Color',Colors(1,:)); %Точка для отображение длины кривой в легенде
                                GraphLabel = ['y = ' num2str(LinearRegressionCoeffs(1,app.ShowNumb)) 'x ' Factor num2str(abs(LinearRegressionCoeffs(2,app.ShowNumb)))];
                                Leg = legend('Данные рассеяния', GraphLabel, ['Дистанция рассеяния = ' num2str(DistanceScatter(app.ShowNumb))],['Cоотношение максимумов: ' num2str(MaxBaseSignal(app.ShowNumb),'%.2e') '/' num2str(MaxShowSignal(app.ShowNumb),'%.2e') ' (' num2str(FreqMaxBaseSignal(app.ShowNumb),'%.2f') '/' num2str(FreqMaxShowSignal(app.ShowNumb),'%.2f') ' Гц)'],['Длина кривой рассеяния: ' num2str(LengthCurve(app.ShowNumb), '%.2e')]);
                            else
                                scatter(BaseSignal,ShowSignal,[],Colors,'*'); %Построение рассеяния
                                plot(BaseSignal,LinearRegressionFun); %Линейная регрессия
                                %Отображение легенды
                                if LinearRegressionCoeffs(2) > 0
                                    Factor = '+ ';
                                else
                                    Factor = '- ';
                                end
                                plot(BaseSignal(1),ShowSignal(1),'.','Color',Colors(1,:)); %Точка для отображение меры рассеяния в легенде
                                plot(BaseSignal(1),ShowSignal(1),'x','Color',Colors(end,:)); %Точка для отображение максимума в легенде
                                plot(BaseSignal(1),ShowSignal(1),'+','Color',Colors(1,:)); %Точка для отображение длины кривой в легенде
                                GraphLabel = ['y = ' num2str(LinearRegressionCoeffs(1)) 'x ' Factor num2str(abs(LinearRegressionCoeffs(2)))];
                                Leg = legend('Данные рассеяния', GraphLabel, ['Дистанция рассеяния = ' num2str(DistanceScatter)],['Cоотношение максимумов: ' num2str(MaxBaseSignal,'%.2e') '/' num2str(MaxShowSignal,'%.2e') ' (' num2str(FreqMaxBaseSignal,'%.2f') '/' num2str(FreqMaxShowSignal,'%.2f') ' Гц)'], ['Длина кривой рассеяния: ' num2str(LengthCurve, '%.2e')]);
                            end
                            legend('boxoff'); %Легенда без рамки
                            Leg.Location = 'best';
                            grid on;
                            xlabel(['Cигнал базового уровня #' num2str(app.CurrentLevels(app.ControlLevelNumb))]);
                            if app.CheckBoxCompareInputSignal.Value %Проверка режима сравнения с спектром исходного сигнала
                                ylabel('Исходный сигнал ускорений');
                            else
                                ylabel(['Сигнал уровня №' num2str(app.CurrentLevels(app.ShowNumb))]); %Названия осей
                            end
                            %Запись информации о расчёте
                            app.RegressionInformationMonotone{s}.Values = [LinearRegressionCoeffs; DistanceScatter; MaxBaseSignal; MaxShowSignal; FreqMaxBaseSignal; FreqMaxShowSignal; LengthCurve];
                            app.RegressionInformationMonotone{s}.HeadersRows = {'a ','b ','Distance ', 'Max base signal ', 'Max show signal ', 'Freq base signal ', 'Freq show signal ', 'Length curve '};
                            clear BaseSignal ShowSignal LengthCurve DistanceScatter LinearRegressionCoeffs...
                                LinearRegressionFun MaxBaseSignal MaxShowSignal FreqMaxBaseSignal FreqMaxShowSignal; %Очистка переменных
                        end
                    case 26 %Рассеяние поверхности спектра затухающих склеек
                        Fig26 = figure(26); %Новое графическое окно
                        Fig26.Name = 'Рассеяние поверхности спектра затухающих склеек';
                        [BaseCovLevel,ShowCovLevel] = CorrectNumbLevel(app,'Exp'); %Получение скорректированных индексов сигнала
                        hold on; %Построение графиков в одних осях
                        BaseSignal = app.SpectrumExpAccelGluedVisualizeFix(:,BaseCovLevel);
                        if app.CheckBoxCompareInputSignal.Value %Проверка режима сравнения с спектром исходного сигнала
                            ShowSignal = app.SpectrumAccelInputExpFix;
                        else
                            for i = 1:2 %Зависимость всех уровней от базового
                                ShowSignal(:,i) = app.SpectrumExpAccelGluedVisualizeFix(:,i); %Сигнал для отображения
                            end
                        end
                        if ~app.CheckBoxLogScale.Value %Проверка типа масштаба осей
                            title('Рассеяние поверхности спектра затухающих склеек');
                        else
                            BaseSignal = log(BaseSignal); ShowSignal = log(ShowSignal);
                            if ~isreal(BaseSignal), BaseSignal = abs(BaseSignal); end
                            if ~isreal(ShowSignal), ShowSignal = abs(ShowSignal); end
                            title('Логарифмическое рассеяние поверхности спектра затухающих склеек');
                        end
                        %Получаем распределение цветов фиксированной длины
                        Colors = jet(length(BaseSignal));
                        Colors = Colors(end:-1:1,:); %Переворот матрицы цветов
                        colormap(Colors);
                        cb = colorbar; %Цветовая шкала
                        cb.Label.String = 'Частоты';
                        %Формирование Colorbar
                        LowFreq = app.SpinnerFixSpectrumLow.Value; HighFreq = app.SpinnerFixSpectrumHigh.Value; %Получение граничных частот
                        for i = 1:length(cb.Ticks)
                            cb.TickLabels{i} = LowFreq + (i - 1)*(HighFreq - LowFreq)/(length(cb.Ticks) - 1); %Метки границ
                        end
                        %Построение линейной регрессии
                        for i = 1:size(ShowSignal,2)
                            LinearRegressionCoeffs(:,i) = polyfit(BaseSignal,ShowSignal(:,i),1); %Коэффициенты для линейной регрессии
                            LinearRegressionFun(:,i) = polyval(LinearRegressionCoeffs(:,i),BaseSignal); %Вычисление значений линейной регрессии
                            DistanceScatter(i) = sum(abs(ShowSignal(:,i) - LinearRegressionFun(:,i))); %Дистанция рассеяния
                            [MaxBaseSignal(i) MaxBaseSignalInd] = max(BaseSignal); [MaxShowSignal(i) MaxShowSignalInd] = max(ShowSignal(:,i)); %Максимумы рассеяния
                            FreqMaxBaseSignal(i) = app.FrequencyExpAccelGluedFix(MaxBaseSignalInd); FreqMaxShowSignal(i) = app.FrequencyExpAccelGluedFix(MaxShowSignalInd); %Частоты соотношения максимумов
                            LengthCurve(i) = 0; %Инициализация длины кривой
                            for j = 1:length(BaseSignal) - 1
                                if DistanceScatter(i) > 1e-15 %Обработка авторегресии
                                    LengthCurve(i) = LengthCurve(i) + sqrt((BaseSignal(j+1) - BaseSignal(j))^2 + (ShowSignal(j+1,i) - ShowSignal(j,i))^2); %Длина кривой
                                elseif (BaseSignal(j+1) >= BaseSignal(j))
                                    LengthCurve(i) = LengthCurve(i) + sqrt((BaseSignal(j+1) - BaseSignal(j))^2 + (ShowSignal(j+1,i) - ShowSignal(j,i))^2); %Длина кривой
                                end
                            end
                            app.RegressionInformationExpAccel.HeadersCols{i} = num2str(i-2); %Заголовки информации
                        end
                        if size(ShowSignal,2) ~= 1 %Проверка сравнения с исходным сигналом
                            scatter(BaseSignal,ShowSignal(:,ShowCovLevel),[],Colors,'*'); %Построение рассеяния
                            plot(BaseSignal,LinearRegressionFun(:,ShowCovLevel)); %Линейная регрессия
                            %Отображение легенды
                            if LinearRegressionCoeffs(2,ShowCovLevel) > 0
                                Factor = '+ ';
                            else
                                Factor = '- ';
                            end
                            plot(BaseSignal(1),ShowSignal(1,ShowCovLevel),'.','Color',Colors(1,:)); %Точка для отображение меры рассеяния в легенде
                            plot(BaseSignal(1),ShowSignal(1,ShowCovLevel),'x','Color',Colors(end,:)); %Точка для отображение максимума в легенде
                            plot(BaseSignal(1),ShowSignal(1,ShowCovLevel),'+','Color',Colors(1,:)); %Точка для отображение длины кривой в легенде
                            GraphLabel = ['y = ' num2str(LinearRegressionCoeffs(1,ShowCovLevel)) 'x ' Factor num2str(abs(LinearRegressionCoeffs(2,ShowCovLevel)))];
                            Leg = legend('Данные рассеяния', GraphLabel, ['Дистанция рассеяния = ' num2str(DistanceScatter(ShowCovLevel))],['Cоотношение максимумов: ' num2str(MaxBaseSignal(ShowCovLevel),'%.2e') '/' num2str(MaxShowSignal(ShowCovLevel),'%.2e') ' (' num2str(FreqMaxBaseSignal(ShowCovLevel),'%.2f') '/' num2str(FreqMaxShowSignal(ShowCovLevel),'%.2f') ' Гц)'], ['Длина кривой рассеяния: ' num2str(LengthCurve(ShowCovLevel), '%.2e')]);
                        else
                            scatter(BaseSignal,ShowSignal,[],Colors,'*'); %Построение рассеяния
                            plot(BaseSignal,LinearRegressionFun); %Линейная регрессия
                            %Отображение легенды
                            if LinearRegressionCoeffs(2) > 0
                                Factor = '+ ';
                            else
                                Factor = '- ';
                            end
                            plot(BaseSignal(1),ShowSignal(1),'.','Color',Colors(1,:)); %Точка для отображение меры рассеяния в легенде
                            plot(BaseSignal(1),ShowSignal(1),'x','Color',Colors(end,:)); %Точка для отображение максимума в легенде
                            plot(BaseSignal(1),ShowSignal(1),'+','Color',Colors(1,:)); %Точка для отображение длины кривой в легенде
                            GraphLabel = ['y = ' num2str(LinearRegressionCoeffs(1)) 'x ' Factor num2str(abs(LinearRegressionCoeffs(2)))];
                            Leg = legend('Данные рассеяния', GraphLabel, ['Дистанция рассеяния = ' num2str(DistanceScatter)], ['Cоотношение максимумов: ' num2str(MaxBaseSignal,'%.2e') '/' num2str(MaxShowSignal,'%.2e') ' (' num2str(FreqMaxBaseSignal,'%.2f') '/' num2str(FreqMaxShowSignal,'%.2f') ' Гц)'], ['Длина кривой рассеяния: ' num2str(LengthCurve, '%.2e')]);
                        end
                        legend('boxoff'); %Легенда без рамки
                        Leg.Location = 'best';
                        grid on;
                        xlabel(['Cигнал базового уровня #' num2str(BaseCovLevel-2)]);
                        if app.CheckBoxCompareInputSignal.Value %Проверка режима сравнения с спектром исходного сигнала
                            ylabel('Исходный сигнал ускорений');
                        else
                            ylabel(['Сигнал уровня №' num2str(ShowCovLevel-2)]); %Названия осей
                        end
                        app.RegressionInformationExpAccel.Values = [LinearRegressionCoeffs; DistanceScatter; MaxBaseSignal; MaxShowSignal; FreqMaxBaseSignal; FreqMaxShowSignal; LengthCurve];
                        app.RegressionInformationExpAccel.HeadersRows = {'a ','b ','Distance ', 'Max base signal ', 'Max show signal ', 'Freq base signal ', 'Freq show signal ', 'Length curve '};
                        clear BaseSignal ShowSignal LengthCurve DistanceScatter LinearRegressionCoeffs...
                            LinearRegressionFun MaxBaseSignal MaxShowSignal FreqMaxBaseSignal FreqMaxShowSignal; %Очистка переменных
                end
            end
            
        end

        % Value changed function: SpinnerControlLevelNumb
        function SpinnerControlLevelNumbValueChanged(app, event)
            if strcmp(app.ButtonCalculate.Tag,'Ok') %Еcли расчёт окончен
                FindDifferenceSurf(app); %Отличия поверхностей
                ListBoxShowFigureValueChanged(app, event); %Отрисовка для заданного уровня
            else
                app.SpinnerControlLevelNumb.Value = 0; %Нулевой уровень для отображения по умолчанию
            end
        end

        % Value changed function: SpinnerFixSpectrumHigh, 
        % SpinnerFixSpectrumLow
        function SpinnerFixSpectrumLowValueChanged(app, event)
            if strcmp(app.ButtonCalculate.Tag,'Ok')
                %Спектры исходных ускорений
                %Полный
                [app.FrequencyAccelInputFullFix, app.SpectrumAccelInputFullFix] = FindFixSpectrum(app, app.FrequencyAccelInputFull, app.SpectrumAccelInputFull);
                %По склейке ускорений
                [app.FrequencyAccelInputGluedFix, app.SpectrumAccelInputGluedFix] = FindFixSpectrum(app, app.FrequencyAccelInputGlued, app.SpectrumAccelInputGlued);
                %По склейке монотонных фрагментов
                for s = 1:3 %[Increase, Neutral, Decrease]
                    [app.FrequencyAccelInputMonotoneFix{s}, app.SpectrumAccelInputMonotoneFix{s}] = FindFixSpectrum(app, app.FrequencyAccelInputMonotone{s}, app.SpectrumAccelInputMonotone{s});
                end
                %По склейке экспонециально затухающих
                [app.FrequencyAccelInputExpFix, app.SpectrumAccelInputExpFix] = FindFixSpectrum(app, app.FrequencyAccelInputExp, app.SpectrumAccelInputExp);
                
                %Поверхность спектра ускорний
                [app.FrequencyAccelGluedFix, app.SpectrumAccelGluedVisualizeFix] = FindFixSpectrum(app, app.FrequencyAccelGlued, app.SpectrumAccelGluedVisualize);
                app.SpectrumAccelGluedVisualizeControlFix = app.SpectrumAccelGluedVisualizeFix; %Контрольная поверхность
                %Поверхность спектра затухающих фрагментов
                [app.FrequencyExpAccelGluedFix, app.SpectrumExpAccelGluedVisualizeFix] = FindFixSpectrum(app, app.FrequencyExpAccelGlued, app.SpectrumExpAccelGluedVisualize);
                app.SpectrumExpAccelGluedVisualizeControlFix = app.SpectrumExpAccelGluedVisualizeFix;
                %Поиск экстремальной частоты
                [~,Index] =  max(abs(app.SpectrumExpAccelGluedVisualizeFix)); %Индексы макс. по всем столбцам
                Index = max(Index); %Индекс максимального элемента
                app.LabelMaxFreq.Text = num2str(app.FrequencyExpAccelGluedFix(Index),'%.2f');
                %Поверхность спектра монотонных склеек
                for s = 1:3 %[Increase, Neutral, Decrease]
                    [app.FrequencyMonotoneAccelGluedFix{s}, app.SpectrumMonotoneAccelGluedVisualizeFix{s}] = FindFixSpectrum(app, app.FrequencyMonotoneAccelGlued{s}, app.SpectrumMonotoneAccelGluedVisualize{s});
                    app.SpectrumMonotoneAccelGluedVisualizeControlFix{s} = app.SpectrumMonotoneAccelGluedVisualizeFix{s}; %Поверхность контроля
                end
                FindDifferenceSurf(app); %Вычисление разницы поверхностей для заданного опорного уровня
                ListBoxShowFigureValueChanged(app, event); %Отрисовка для заданного уровня
            else
                app.SpinnerFixSpectrumLow.Value = 0; %Обнуление частоты, если расчёт не выполнен
                app.SpinnerFixSpectrumHigh.Value = 0; %Обнуление частоты, если расчёт не выполнен
            end
        end

        % Value changed function: SpinnerShowNumb
        function SpinnerShowNumbValueChanged(app, event)
            if strcmp(app.ButtonCalculate.Tag,'Ok') %Еcли расчёт окончен
                if app.SpinnerShowNumb.Value <= max(app.CurrentLevels) && app.SpinnerShowNumb.Value >= min(app.CurrentLevels) %Ограничение отображения по числу уровней
                    app.ShowNumb = find(app.CurrentLevels == app.SpinnerShowNumb.Value);
                    ListBoxShowFigureValueChanged(app, event); %Отрисовка для заданного уровня
                end
            else
                app.SpinnerShowNumb.Value = 0; %Нулевой уровень для отображения по умолчанию
            end
        end

        % Button pushed function: ButtonAngleCoeffcients
        function ButtonAngleCoeffcientsPushed(app, event)
            app.AngleCoeffUI = AngleCoefficients; %Вызов окна задания коэффициенто
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main = uifigure;
            app.Main.Name = 'Proof';
            app.FilesForCalculate = uipanel(app.Main);
            app.FilesForCalculate.Title = 'Файлы для расчёта';
            app.Label_2 = uilabel(app.FilesForCalculate);
            app.Label_2.Text = 'Перемещения:';
            app.EditDisplacementName = uieditfield(app.FilesForCalculate, 'text');
            app.EditDisplacementName.Editable = 'off';
            app.ButtonDefineDisplacementName = uibutton(app.FilesForCalculate, 'push');
            app.ButtonDefineDisplacementName.ButtonPushedFcn = createCallbackFcn(app, @ButtonDefineDisplacementNamePushed, true);
            app.ButtonDefineDisplacementName.Icon = 'Share.png';
            app.ButtonDefineDisplacementName.Text = ':';
            app.Label_3 = uilabel(app.FilesForCalculate);
            app.Label_3.Text = 'Номер отсчётной строки для считывания данных:';
            app.EditStartReadNumb = uieditfield(app.FilesForCalculate, 'numeric');
            app.EditStartReadNumb.Limits = [1 Inf];
            app.EditStartReadNumb.ValueDisplayFormat = '%.0f';
            app.EditStartReadNumb.Value = 12;
            app.ButtonDefineAccelName = uibutton(app.FilesForCalculate, 'push');
            app.ButtonDefineAccelName.ButtonPushedFcn = createCallbackFcn(app, @ButtonDefineAccelNamePushed, true);
            app.ButtonDefineAccelName.Icon = 'Share.png';
            app.ButtonDefineAccelName.Text = ':';
            app.Label_9 = uilabel(app.FilesForCalculate);
            app.Label_9.Text = 'Ускорения:';
            app.EditAccelName = uieditfield(app.FilesForCalculate, 'text');
            app.EditAccelName.Editable = 'off';
            app.CalculateSettings = uipanel(app.Main);
            app.CalculateSettings.Title = 'Параметры расчёта';
            app.Label_5 = uilabel(app.CalculateSettings);
            app.Label_5.Text = 'Длина минимального временного фрагмента:';
            app.EditCutProcent = uieditfield(app.CalculateSettings, 'numeric');
            app.EditCutProcent.Limits = [0.01 0.99];
            app.EditCutProcent.Value = 0.2;
            app.Label_6 = uilabel(app.CalculateSettings);
            app.Label_6.Text = 'Частота среза, Гц:';
            app.EditCutOffFrequency = uieditfield(app.CalculateSettings, 'numeric');
            app.EditCutOffFrequency.Limits = [0 Inf];
            app.EditCutOffFrequency.ValueChangedFcn = createCallbackFcn(app, @CalculateParamsNew, true);
            app.EditCutOffFrequency.Value = 0.1;
            app.CheckBoxCorrectDisplacement = uicheckbox(app.CalculateSettings);
            app.CheckBoxCorrectDisplacement.ValueChangedFcn = createCallbackFcn(app, @CalculateParamsNew, true);
            app.CheckBoxCorrectDisplacement.Text = 'Корректировка линейного дрейфа перемещений';
            app.ButtonCalculate = uibutton(app.CalculateSettings, 'push');
            app.ButtonCalculate.ButtonPushedFcn = createCallbackFcn(app, @ButtonCalculatePushed, true);
            app.ButtonCalculate.Enable = 'off';
            app.ButtonCalculate.Text = 'Расчёт';
            app.ButtonSave = uibutton(app.CalculateSettings, 'push');
            app.ButtonSave.ButtonPushedFcn = createCallbackFcn(app, @ButtonSavePushed, true);
            app.ButtonSave.Enable = 'off';
            app.ButtonSave.Text = 'Сохранить';
            app.Label_10 = uilabel(app.CalculateSettings);
            app.Label_10.Text = 'Приведение склеек к длине:';
            app.DropDownCorrectLength = uidropdown(app.CalculateSettings);
            app.DropDownCorrectLength.Items = {'Нет', 'Исходной', 'Максимальной'};
            app.DropDownCorrectLength.Value = 'Нет';
            app.Label_11 = uilabel(app.CalculateSettings);
            app.Label_11.Text = 'Высота уровней:';
            app.EditLevelsStep = uieditfield(app.CalculateSettings, 'numeric');
            app.EditLevelsStep.Limits = [0.01 Inf];
            app.EditLevelsStep.ValueDisplayFormat = '%11.6g';
            app.EditLevelsStep.Value = 3000;
            app.Label_12 = uilabel(app.CalculateSettings);
            app.Label_12.Text = 'Точность аппроксимации перемещений:';
            app.EditAccuracy = uieditfield(app.CalculateSettings, 'numeric');
            app.EditAccuracy.Limits = [0 1];
            app.EditAccuracy.ValueChangedFcn = createCallbackFcn(app, @CalculateParamsNew, true);
            app.EditAccuracy.Value = 1e-07;
            app.Label_14 = uilabel(app.CalculateSettings);
            app.Label_14.Text = 'Точность аппроксимации затухания:';
            app.EditAccuracyExp = uieditfield(app.CalculateSettings, 'numeric');
            app.EditAccuracyExp.Limits = [0 1];
            app.EditAccuracyExp.Value = 1e-07;
            app.Label_15 = uilabel(app.CalculateSettings);
            app.Label_15.Text = 'Предельное значение нулевых уровней:';
            app.EditLimExpAccelLevel = uieditfield(app.CalculateSettings, 'numeric');
            app.EditLimExpAccelLevel.Limits = [0 1];
            app.EditLimExpAccelLevel.Value = 0.05;
            app.Label_16 = uilabel(app.CalculateSettings);
            app.Label_16.Text = 'Глубина прохода для склейки:';
            app.EditDepthGluing = uieditfield(app.CalculateSettings, 'numeric');
            app.EditDepthGluing.Limits = [0 1];
            app.Label_17 = uilabel(app.CalculateSettings);
            app.Label_17.Text = 'Частота выделения декремента затуханий:';
            app.EditFreqDecrement = uieditfield(app.CalculateSettings, 'numeric');
            app.EditFreqDecrement.Limits = [1 Inf];
            app.EditFreqDecrement.Value = 5;
            app.LabelMaxMin = uilabel(app.CalculateSettings);
            app.LabelMaxMin.Text = '';
            app.ButtonLoadSettings = uibutton(app.CalculateSettings, 'push');
            app.ButtonLoadSettings.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadSettingsPushed, true);
            app.ButtonLoadSettings.Text = 'Подгрузка настроек';
            app.Label_18 = uilabel(app.CalculateSettings);
            app.Label_18.Text = 'Модель выделения декремента затуханий:';
            app.DropDownModelApprox = uidropdown(app.CalculateSettings);
            app.DropDownModelApprox.Items = {'Exponential', 'B-Spline', 'Rational', 'Power'};
            app.DropDownModelApprox.Value = 'B-Spline';
            app.LabelMaxFreq = uilabel(app.CalculateSettings);
            app.LabelMaxFreq.Text = '';
            app.Label_22 = uilabel(app.CalculateSettings);
            app.Label_22.Text = 'Коэффициент перекрытия границ уровней:';
            app.EditOverlapFactor = uieditfield(app.CalculateSettings, 'numeric');
            app.EditOverlapFactor.Limits = [0 1];
            app.EditOverlapFactor.Value = 0.5;
            app.Label_21 = uilabel(app.CalculateSettings);
            app.Label_21.Text = 'Точность аппроксимации спектров:';
            app.EditAccuracySpectrum = uieditfield(app.CalculateSettings, 'numeric');
            app.EditAccuracySpectrum.Limits = [0 1];
            app.EditAccuracySpectrum.ValueChangedFcn = createCallbackFcn(app, @CalculateParamsNew, true);
            app.EditAccuracySpectrum.Value = 0.95;
            app.CheckBoxNormalizeMode = uicheckbox(app.CalculateSettings);
            app.CheckBoxNormalizeMode.ValueChangedFcn = createCallbackFcn(app, @CalculateParamsNew, true);
            app.CheckBoxNormalizeMode.Text = 'Норм. сигналов';
            app.ButtonAngleCoeffcients = uibutton(app.CalculateSettings, 'push');
            app.ButtonAngleCoeffcients.ButtonPushedFcn = createCallbackFcn(app, @ButtonAngleCoeffcientsPushed, true);
            app.ButtonAngleCoeffcients.Icon = 'Window_settings.png';
            app.ButtonAngleCoeffcients.Text = '';
            app.ShowResults = uipanel(app.Main);
            app.ShowResults.Title = 'Отображение результатов расчёта';
            app.ListBoxShowFigure = uilistbox(app.ShowResults);
            app.ListBoxShowFigure.Items = {'Выбранные сигналы', 'Сравнение выбранных сигналов', 'Ускорения', 'Спектр ускорений', 'Перемещения', 'Фрагментированные перемещения', 'Склейка ускорений', 'Спектр склейки', 'Поверхность спектра ускорений', 'Склейка монотонных фрагментов', 'Спектр монотонных склеек', 'Поверхность спектра монотонных склеек', 'Склейка затухающих фрагментов', 'Спектр затухающих склеек', 'Поверхность спектра затухающих', 'Поверхность декрементов верхних частей', 'Поверхность декрементов нижних частей', 'Разница поверхности спектра ускорений', 'Разница поверхности спектра монотонных склеек', 'Разница поверхности спектра затухающих склеек', 'Ковариация поверхности спектра ускорений', 'Ковариация поверхности спектра монотонных склеек', 'Ковариация поверхности спектра затухающих склеек', 'Рассеяние поверхности спектра ускорений', 'Рассеяние поверхности спектра монотонных склеек', 'Рассеяние поверхности спектра затухающих склеек'};
            app.ListBoxShowFigure.Multiselect = 'on';
            app.ListBoxShowFigure.ValueChangedFcn = createCallbackFcn(app, @ListBoxShowFigureValueChanged, true);
            app.ListBoxShowFigure.Value = {'Выбранные сигналы'};
            app.Label_8 = uilabel(app.ShowResults);
            app.Label_8.Text = 'Уровень для отображения:';
            app.SpinnerShowNumb = uispinner(app.ShowResults);
            app.SpinnerShowNumb.ValueDisplayFormat = '%.0f';
            app.SpinnerShowNumb.ValueChangedFcn = createCallbackFcn(app, @SpinnerShowNumbValueChanged, true);
            app.Label_13 = uilabel(app.ShowResults);
            app.Label_13.Text = 'Нижняя частота среза:';
            app.SpinnerFixSpectrumLow = uispinner(app.ShowResults);
            app.SpinnerFixSpectrumLow.Limits = [0 Inf];
            app.SpinnerFixSpectrumLow.ValueChangedFcn = createCallbackFcn(app, @SpinnerFixSpectrumLowValueChanged, true);
            app.CheckBoxLogScale = uicheckbox(app.ShowResults);
            app.CheckBoxLogScale.ValueChangedFcn = createCallbackFcn(app, @CheckBoxLogScaleValueChanged, true);
            app.CheckBoxLogScale.Text = 'Лог. масштаб';
            app.Label_19 = uilabel(app.ShowResults);
            app.Label_19.Text = 'Опорный уровень для сравнения:';
            app.SpinnerControlLevelNumb = uispinner(app.ShowResults);
            app.SpinnerControlLevelNumb.ValueChangedFcn = createCallbackFcn(app, @SpinnerControlLevelNumbValueChanged, true);
            app.Label_20 = uilabel(app.ShowResults);
            app.Label_20.Text = 'Верхняя частота среза:';
            app.SpinnerFixSpectrumHigh = uispinner(app.ShowResults);
            app.SpinnerFixSpectrumHigh.Limits = [0 Inf];
            app.SpinnerFixSpectrumHigh.ValueChangedFcn = createCallbackFcn(app, @SpinnerFixSpectrumLowValueChanged, true);
            app.CheckBoxCompareInputSignal = uicheckbox(app.ShowResults);
            app.CheckBoxCompareInputSignal.ValueChangedFcn = createCallbackFcn(app, @ListBoxShowFigureValueChanged, true);
            app.CheckBoxCompareInputSignal.Text = 'Сравнение с ускорениями';
            app.CheckBoxSmoothingOption = uicheckbox(app.ShowResults);
            app.CheckBoxSmoothingOption.ValueChangedFcn = createCallbackFcn(app, @ListBoxShowFigureValueChanged, true);
            app.CheckBoxSmoothingOption.Text = 'Сглаживание поверхности';
            app.CheckBoxSmoothingOption.Value = true;
            app.InputFiles = uipanel(app.Main);
            app.InputFiles.Title = 'Исходные файлы';
            app.ListBoxInput = uilistbox(app.InputFiles);
            app.ListBoxInput.Items = {};
            app.ListBoxInput.Multiselect = 'on';
            app.ListBoxInput.ValueChangedFcn = createCallbackFcn(app, @ListBoxInputValueChanged, true);
            app.ListBoxInput.Value = {};
            app.ButtonTrash = uibutton(app.InputFiles, 'push');
            app.ButtonTrash.ButtonPushedFcn = createCallbackFcn(app, @ButtonTrashPushed, true);
            app.ButtonTrash.Icon = 'TrashBlack.png';
            app.ButtonTrash.Text = '';
            app.ButtonClear = uibutton(app.InputFiles, 'push');
            app.ButtonClear.ButtonPushedFcn = createCallbackFcn(app, @ButtonClearPushed, true);
            app.ButtonClear.Icon = 'New.png';
            app.ButtonClear.Text = '';
            app.ButtonAdd = uibutton(app.InputFiles, 'push');
            app.ButtonAdd.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddPushed, true);
            app.ButtonAdd.Icon = 'Plus.png';
            app.ButtonAdd.Text = '';
            app.ButtonInfo = uibutton(app.InputFiles, 'push');
            app.ButtonInfo.ButtonPushedFcn = createCallbackFcn(app, @ButtonInfoPushed, true);
            app.ButtonInfo.Icon = 'Info.png';
            app.ButtonInfo.Text = '';
            app.ButtonGroupMultiPlotSettings = uibuttongroup(app.InputFiles);
            app.ButtonGroupMultiPlotSettings.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupMultiPlotSettingsSelectionChanged, true);
            app.ButtonGroupMultiPlotSettings.Title = 'Параметры просмотра сигналов';
            app.ButtonNothing = uiradiobutton(app.ButtonGroupMultiPlotSettings);
            app.ButtonNothing.Text = 'Без обработки';
            app.ButtonZero = uiradiobutton(app.ButtonGroupMultiPlotSettings);
            app.ButtonZero.Text = 'Приведение к нулю';
            app.ButtonZero.Value = true;
            app.ButtonLinear = uiradiobutton(app.ButtonGroupMultiPlotSettings);
            app.ButtonLinear.Text = 'Линейная корректировка';
            app.ButtonClearChoise = uibutton(app.InputFiles, 'push');
            app.ButtonClearChoise.ButtonPushedFcn = createCallbackFcn(app, @ButtonClearChoisePushed, true);
            app.ButtonClearChoise.Icon = 'Repeat.png';
            app.ButtonClearChoise.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProofAccel_main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main)
        end
    end
end